classdef batmesh < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        pythonCB          matlab.ui.control.CheckBox
        EF_binFream       matlab.ui.control.Spinner
        Bt_importbin      matlab.ui.control.Button
        CB_datarot        matlab.ui.control.CheckBox
        Bt_makeTraindata  matlab.ui.control.Button
        Bt_catdata        matlab.ui.control.Button
        Bt_removemarker   matlab.ui.control.Button
        Bt_remark         matlab.ui.control.Button
        EF_y2             matlab.ui.control.Spinner
        EF_y1             matlab.ui.control.Spinner
        EF_x2             matlab.ui.control.Spinner
        EF_x1             matlab.ui.control.Spinner
        LB_marked         matlab.ui.control.ListBox
        Bt_save2work      matlab.ui.control.Button
        Bt_filterReset    matlab.ui.control.Button
        Bt_plotvalue      matlab.ui.control.Button
        Bt_filterSave     matlab.ui.control.Button
        fitlerMean        matlab.ui.control.NumericEditField
        Label_15          matlab.ui.control.Label
        Label_14          matlab.ui.control.Label
        fitlerMin         matlab.ui.control.NumericEditField
        Bt_mark           matlab.ui.control.Button
        Label_17          matlab.ui.control.Label
        Bt_makepos        matlab.ui.control.Button
        Bt_importdataWs   matlab.ui.control.Button
        fitlerMax         matlab.ui.control.NumericEditField
        Label_13          matlab.ui.control.Label
        EF_meanValue      matlab.ui.control.Label
        EF_minValue       matlab.ui.control.Label
        EF_maxValue       matlab.ui.control.Label
        num_total         matlab.ui.control.Label
        num_now           matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        Bt_nextmesh       matlab.ui.control.Button
        Bt_perviousmesh   matlab.ui.control.Button
        Bt_importdata     matlab.ui.control.Button
        Label_7           matlab.ui.control.Label
        RightPanel        matlab.ui.container.Panel
        GridLayout2       matlab.ui.container.GridLayout
        Label_16          matlab.ui.control.Label
        UIAxes_plot       matlab.ui.control.UIAxes
        UIAxes            matlab.ui.control.UIAxes
        UIAxes_face       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        csvdata % 读入的数据
        csvfilter %优化滤波后的，多次迭代过程
        csvorg %最原始的，不满意可以重置
        index %当前浏览的图片
        max_index %最大数量
        maxplot %所有的最大值
        minplot %所有的最小值
        meanplot %所有的均值
        markimg
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.index=0;
            app.max_index=0;
            app.Bt_nextmesh.Enable = 0;
            app.Bt_perviousmesh.Enable = 0;
            app.maxplot=[]; %所有的最大值
            app.minplot=[]; %所有的最小值
            app.meanplot=[]; %所有的均值
            app.csvorg=[];
            app.csvdata=[];
            app.csvfilter=[];
            app.markimg={};
        end

        % Button pushed function: Bt_importdata
        function Bt_importdataButtonPushed(app, event)
            csvpath=uigetdir('D:\Work_F\CNNFyj','选择csv文件夹');
            if csvpath==0
                return;
            end
            
            app.csvdata=imgimportapp(csvpath,[app.EF_x1.Value app.EF_x2.Value],[app.EF_y1.Value app.EF_y2.Value]);
            app.csvorg=app.csvdata;
            datasize=size(app.csvdata);
            app.num_total.Text=num2str(datasize(3));
            app.max_index=datasize(3);
            
            mesh(app.UIAxes,app.csvdata(:,:,1));
            mesh(app.UIAxes_face,app.csvdata(:,:,1));
            view(app.UIAxes_face,[0,-90]);
            app.num_now.Text=num2str(1);
            app.index=1;
            
            temp=app.csvdata(:,:,1);
            app.EF_maxValue.Text=num2str(max(max(temp)));
            app.EF_minValue.Text=num2str(min(min(temp)));
            app.EF_meanValue.Text=num2str(mean(mean(temp)));
            
            app.Bt_nextmesh.Enable = 1;
            app.Bt_filterSave.Enable=1;
            
            for n=1:app.max_index
                app.maxplot(end+1)=max(max(app.csvdata(:,:,n)));
                app.minplot(end+1)=min(min(app.csvdata(:,:,n)));
                app.meanplot(end+1)=mean(mean(app.csvdata(:,:,n)));
            end
            plot(app.UIAxes_plot,app.maxplot);
            hold(app.UIAxes_plot,'on');
            plot(app.UIAxes_plot,app.minplot);
            plot(app.UIAxes_plot,app.meanplot);
            hold(app.UIAxes_plot,'off');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {595, 595};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: Bt_perviousmesh
        function Bt_perviousmeshButtonPushed(app, event)
            if app.index==1
                %msgbox('当前为第一个图像数据。不能往前了。','消息提示',"modal")
                app.Bt_perviousmesh.Enable=0;
                return;
            end
            app.index=app.index-1;
            mesh(app.UIAxes,app.csvdata(:,:,app.index));
            mesh(app.UIAxes_face,app.csvdata(:,:,app.index));
            view(app.UIAxes_face,[0,-90]);
            temp=app.csvdata(:,:,app.index);
            app.EF_maxValue.Text=num2str(max(max(temp)));
            app.EF_minValue.Text=num2str(min(min(temp)));
            app.EF_meanValue.Text=num2str(mean(mean(temp)));
            app.Bt_nextmesh.Enable=1;
            app.num_now.Text=num2str(app.index);
            if app.index==1
                %msgbox('当前为第一个图像数据。不能往前了。','消息提示',"modal")
                app.Bt_perviousmesh.Enable=0;
                return;
            end
        end

        % Button pushed function: Bt_nextmesh
        function Bt_nextmeshButtonPushed(app, event)
            app.index=app.index+1;
            app.Bt_perviousmesh.Enable=1;
            mesh(app.UIAxes,app.csvdata(:,:,app.index));
            mesh(app.UIAxes_face,app.csvdata(:,:,app.index));
            view(app.UIAxes_face,[0,-90]);
            temp=app.csvdata(:,:,app.index);
            app.EF_maxValue.Text=num2str(max(max(temp)));
            app.EF_minValue.Text=num2str(min(min(temp)));
            app.EF_meanValue.Text=num2str(mean(mean(temp)));
            app.num_now.Text=num2str(app.index);
            if app.index==app.max_index
                %msgbox('当前为最后一个图像数据。不能再往后了。','消息提示',"modal")
                app.Bt_nextmesh.Enable=0;
                return;
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)

            key = event.Key;
            switch key
                case 'leftarrow'
                    if app.index>1
                        Bt_perviousmeshButtonPushed(app, event);
                    elseif app.index==1
                        app.Bt_perviousmesh.Enable=0;
                    end
                case 'rightarrow'
                    if app.index<app.max_index
                        Bt_nextmeshButtonPushed(app, event);
                    elseif app.index==app.max_index
                        app.Bt_nextmesh.Enable=0;
                    end
                otherwise
            end
        end

        % Button pushed function: Bt_filterSave
        function Bt_filterSaveButtonPushed(app, event)
            app.Bt_filterSave.Enable=0;       
            app.csvfilter=app.csvdata;
            w = waitbar(0,'0%','Name','正在处理...');
            s=size(app.csvfilter);
            for k=1:s(3)
                for i=1:s(1)
                    for j=1:s(2)
                    
                        if app.csvdata(i,j,k)>app.fitlerMax.Value || app.csvdata(i,j,k)<app.fitlerMin.Value
                            app.csvdata(i,j,k)=app.fitlerMean.Value;
                        end
                        
                    end
                end
                waitbar(k/s(3),w,sprintf('%d / %d',k,s(3)));
            end
            
            app.index=1;
            app.Bt_perviousmesh.Enable=0;
            app.Bt_nextmesh.Enable=1;
            mesh(app.UIAxes,app.csvdata(:,:,app.index));
            mesh(app.UIAxes_face,app.csvdata(:,:,app.index));
            view(app.UIAxes_face,[0,-90]);
            temp=app.csvdata(:,:,app.index);
            app.EF_maxValue.Text=num2str(max(max(temp)));
            app.EF_minValue.Text=num2str(min(min(temp)));
            app.EF_meanValue.Text=num2str(mean(mean(temp)));
            app.num_now.Text=num2str(app.index);
            app.maxplot=[]; %所有的最大值
            app.minplot=[]; %所有的最小值
            app.meanplot=[]; %所有的均值
            for n=1:app.max_index
                app.maxplot(end+1)=max(max(app.csvdata(:,:,n)));
                app.minplot(end+1)=min(min(app.csvdata(:,:,n)));
                app.meanplot(end+1)=mean(mean(app.csvdata(:,:,n)));
            end
            plot(app.UIAxes_plot,app.maxplot);
            hold(app.UIAxes_plot,'on');
            plot(app.UIAxes_plot,app.minplot);
            plot(app.UIAxes_plot,app.meanplot);
            hold(app.UIAxes_plot,'off');
            app.Bt_filterSave.Enable=1;
            delete(w);
        end

        % Button pushed function: Bt_plotvalue
        function Bt_plotvalueButtonPushed(app, event)
            figure;
            plot(app.maxplot);
            hold on;
            plot(app.minplot);
            plot(app.meanplot);
            hold off;
        end

        % Button pushed function: Bt_filterReset
        function Bt_filterResetButtonPushed(app, event)
            app.index=1;
            app.Bt_perviousmesh.Enable=0;
            app.Bt_nextmesh.Enable=1;
            app.csvdata=app.csvorg;
            mesh(app.UIAxes,app.csvdata(:,:,app.index));
            temp=app.csvdata(:,:,app.index);
            app.EF_maxValue.Text=num2str(max(max(temp)));
            app.EF_minValue.Text=num2str(min(min(temp)));
            app.EF_meanValue.Text=num2str(mean(mean(temp)));
            app.maxplot=[]; %所有的最大值
            app.minplot=[]; %所有的最小值
            app.meanplot=[]; %所有的均值
            app.num_now.Text=num2str(app.index);
            for n=1:app.max_index
                app.maxplot(end+1)=max(max(app.csvdata(:,:,n)));
                app.minplot(end+1)=min(min(app.csvdata(:,:,n)));
                app.meanplot(end+1)=mean(mean(app.csvdata(:,:,n)));
            end
            plot(app.UIAxes_plot,app.maxplot);
            hold(app.UIAxes_plot,'on');
            plot(app.UIAxes_plot,app.minplot);
            plot(app.UIAxes_plot,app.meanplot);
            hold(app.UIAxes_plot,'off');
        end

        % Button pushed function: Bt_save2work
        function Bt_save2workButtonPushed(app, event)
            prompt = {'保存到工作区的名字:'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'csvdata'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            assignin('base',answer{1},app.csvdata);
        end

        % Button pushed function: Bt_importdataWs
        function Bt_importdataWsButtonPushed(app, event)
            val=evalin('base', 'who');
            sel=listdlg("ListString",val, ...
                    "Name","选择工作区中的三维数据", ...
                    'ListSize',[300,150],...
                    "OKString","导入", ...
                    "CancelString","取消");
            if isempty(sel)
                return;
            end
            app.csvdata=evalin('base',val{sel});
            
            if app.pythonCB.Value==1
                app.csvdata=double(app.csvdata);
                datasize=size(app.csvdata);
                temp=zeros(datasize(2),datasize(3),datasize(1));
                for i=1:datasize(1)
                    temp(:,:,i)=app.csvdata(i,:,:);
                end
                app.csvdata=temp;
            end
            
            datasize=size(app.csvdata);
            app.num_total.Text=num2str(datasize(3));
            app.max_index=datasize(3);
            mesh(app.UIAxes,app.csvdata(:,:,1));
            mesh(app.UIAxes_face,app.csvdata(:,:,1));
            view(app.UIAxes_face,[0,-90]);
            app.num_now.Text=num2str(1);
            app.index=1;
            
            temp=app.csvdata(:,:,1);
            app.EF_maxValue.Text=num2str(max(max(temp)));
            app.EF_minValue.Text=num2str(min(min(temp)));
            app.EF_meanValue.Text=num2str(mean(mean(temp)));

            app.Bt_nextmesh.Enable = 1;
            app.Bt_filterSave.Enable=1;
            
            for n=1:app.max_index
                app.maxplot(end+1)=max(max(app.csvdata(:,:,n)));
                app.minplot(end+1)=min(min(app.csvdata(:,:,n)));
                app.meanplot(end+1)=mean(mean(app.csvdata(:,:,n)));
            end
           
            
            plot(app.UIAxes_plot,app.maxplot);
            hold(app.UIAxes_plot,'on');
            plot(app.UIAxes_plot,app.minplot);
            plot(app.UIAxes_plot,app.meanplot);
            hold(app.UIAxes_plot,'off');
        end

        % Button pushed function: Bt_makepos
        function Bt_makeposButtonPushed(app, event)
            
            prompt = {'跳转到第几个数据:'};
            dlgtitle = 'Input';
            dims = [1 5];
            definput = {'0'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if isempty(answer)
                return;
            end
            num=str2double(answer{1});
            if num>app.max_index || num<0
                msgbox('超出了选取范围。','消息提示',"modal");
                return;
            end
            if num==0
                app.Bt_nextmesh.Enable = 1;
                app.Bt_perviousmesh.Enable=0;
            elseif num==app.max_index
                app.Bt_nextmesh.Enable = 0;
                app.Bt_perviousmesh.Enable=1;
            else
                app.Bt_nextmesh.Enable = 1;
                app.Bt_perviousmesh.Enable=1;
            end
            app.index=num;
            mesh(app.UIAxes,app.csvdata(:,:,app.index));
            mesh(app.UIAxes_face,app.csvdata(:,:,app.index));
            view(app.UIAxes_face,[0,-90]);
            app.num_now.Text=num2str(app.index);

            temp=app.csvdata(:,:,app.index);
            app.EF_maxValue.Text=num2str(max(max(temp)));
            app.EF_minValue.Text=num2str(min(min(temp)));
            app.EF_meanValue.Text=num2str(mean(mean(temp)));
            
        end

        % Value changed function: LB_marked
        function LB_markedValueChanged(app, event)
            value = app.LB_marked.Value;
            app.index=str2double(value);
            app.num_now.Text=num2str(app.index);
            mesh(app.UIAxes,app.csvdata(:,:,app.index));
            mesh(app.UIAxes_face,app.csvdata(:,:,app.index));
            view(app.UIAxes_face,[0,-90]);
        end

        % Button pushed function: Bt_mark
        function Bt_markButtonPushed(app, event)
            
            nowlist=app.LB_marked.Items;
            if isempty(nowlist)
                app.markimg{end+1}=num2str(app.index);
                app.LB_marked.Items=app.markimg;
            else
                numItem=size(app.LB_marked.Items);
                for i= 1:numItem(2)
                    if strcmp(app.markimg{i},num2str(app.index))
                        return;
                    end
                end
                app.markimg{end+1}=num2str(app.index);
                app.LB_marked.Items=app.markimg;
            end
        end

        % Button pushed function: Bt_remark
        function Bt_remarkButtonPushed(app, event)
            if isempty(app.LB_marked.Items)
                return;
            end
            if isempty(app.LB_marked.Value)
                return;
            else
                numItem=size(app.LB_marked.Items);
                for i= 1:numItem(2)
                    if strcmp(app.markimg{i},app.LB_marked.Value)
                        app.markimg(i)=[];
                        break;
                    end
                end
                app.LB_marked.Items=app.markimg;
            end
        end

        % Button pushed function: Bt_removemarker
        function Bt_removemarkerButtonPushed(app, event)
            num=size(app.markimg);
            remnum=zeros(1,num(2));
            for i=1:num(2)
                remnum(i)=str2double(app.markimg{i});
            end
            
            app.csvdata(:,:,remnum)=[];
            datasize=size(app.csvdata);
            app.num_total.Text=num2str(datasize(3));
            app.max_index=datasize(3);
            
            mesh(app.UIAxes,app.csvdata(:,:,1));
            mesh(app.UIAxes_face,app.csvdata(:,:,1));
            view(app.UIAxes_face,[0,-90]);
            app.num_now.Text=num2str(1);
            app.index=1;
            
            temp=app.csvdata(:,:,1);
            app.EF_maxValue.Text=num2str(max(max(temp)));
            app.EF_minValue.Text=num2str(min(min(temp)));
            app.EF_meanValue.Text=num2str(mean(mean(temp)));

            app.Bt_nextmesh.Enable = 1;
            app.Bt_filterSave.Enable=1;
            
            for n=1:app.max_index
                app.maxplot(end+1)=max(max(app.csvdata(:,:,n)));
                app.minplot(end+1)=min(min(app.csvdata(:,:,n)));
                app.meanplot(end+1)=mean(mean(app.csvdata(:,:,n)));
            end
            plot(app.UIAxes_plot,app.maxplot);
            hold(app.UIAxes_plot,'on');
            plot(app.UIAxes_plot,app.minplot);
            plot(app.UIAxes_plot,app.meanplot);
            hold(app.UIAxes_plot,'off');
            app.markimg={};
            app.LB_marked.Items={};
        end

        % Button pushed function: Bt_catdata
        function Bt_catdataButtonPushed(app, event)
            val=evalin('base', 'who');
            sel=listdlg("ListString",val, ...
                    "Name","选择工作区中的三维数据", ...
                    'ListSize',[300,150],...
                    "OKString","合并", ...
                    "CancelString","取消");
            if isempty(sel)
                return;
            end
            for i=1:length(sel)
                traindata=cat(3,traindata,evalin('base',val{sel(i)}));
            end
            
            prompt = {'保存到工作区的名字:'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'csvdata'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            assignin('base',answer{1},traindata);
        end

        % Button pushed function: Bt_makeTraindata
        function Bt_makeTraindataButtonPushed(app, event)
            val=evalin('base', 'who');
            sel=listdlg("ListString",val, ...
                    "Name","选择训练数据", ...
                    'ListSize',[300,150],...
                    "OKString","导入", ...
                    "CancelString","取消");
            if isempty(sel)
                return;
            end
            traindata=evalin('base',val{sel});
            val=evalin('base', 'who');
            sel=listdlg("ListString",val, ...
                    "Name","选择标签数据", ...
                    'ListSize',[300,150],...
                    "OKString","导入", ...
                    "CancelString","取消");
            labeldata=evalin('base',val{sel});
            
            if app.CB_datarot.Value==1
                traindata=cat(3,traindata,rot90(traindata),rot90(traindata,2),rot90(traindata,3));
                labeldata=cat(3,labeldata,rot90(labeldata),rot90(labeldata,2),rot90(labeldata,3));
            end
            tsize=size(traindata);
            lsize=size(labeldata);
            %两个数据尺寸不一样的时候，个保存个的尺寸，长度的一样
            
            if tsize(3)~=lsize(3)
                msgbox('两个数据不一样长。','消息提示',"modal");
                return;
            %elseif tsize(1)~=lsize(1) || tsize(2)~=lsize(2)
            %    msgbox('两个数据尺寸不一样。','消息提示',"modal");
            %    return;
            end
            
            th=tsize(1);
            tw=tsize(2);
            num=tsize(3);

            lh=lsize(1);
            lw=lsize(2);
            %lnum=lsize(3);


            ttn=fix(num*0.05);
            testdatain=zeros(th,tw,ttn);
            testlabel=zeros(lh,lw,ttn);
            r=randperm(num);%随机排列一下
            r=r(1:ttn);
            for i=1:ttn
                testdatain(:,:,i)=traindata(:,:,r(i));%%%%测试集输入数据
                testlabel(:,:,i)=labeldata(:,:,r(i));%%%%测试集标签数据
            end
            traindata(:,:,r)=[];%%%%%刨去测试剩下的部分
            labeldata(:,:,r)=[];%%%%%刨去测试剩下的部分
            num=num-ttn;


            n=fix(num*0.15);
            varmaid=zeros(th,tw,n);
            varlabel=zeros(lh,lw,n);
            r=randperm(num);
            r=r(1:n);
            
            for i=1:n
                varmaid(:,:,i)=traindata(:,:,r(i));%%%%验证集输入数据
                varlabel(:,:,i)=labeldata(:,:,r(i));%%%%验证集标签数据
            end
            traindata(:,:,r)=[];%%%%%刨去验证剩下的部分
            labeldata(:,:,r)=[];%%%%%刨去验证剩下的部分
            
            
            button=questdlg('产生哪种类型的训练数据？', 'Continue Operation','Matlab','Python','取消','取消');
            if strcmp(button,'Python')
                indext=randperm(n);
                varmaid=varmaid(:,:,indext);
                varlabel=varlabel(:,:,indext);
                indext=randperm(num-n);
                traindata=traindata(:,:,indext);
                labeldata=labeldata(:,:,indext);
                
                assignin('base','varinput',varmaid);
                assignin('base','varlabel',varlabel);
                assignin('base','traininput',traindata);
                assignin('base','trainlable',labeldata);
                
                prompt = {'输入保存路径'};
                dlgtitle = '保存路径';
                dims = [1 35];
                definput = {'输入完整的保存路径，要带文件名称,示例C:\data.mat'};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                
                save(answer{0}, 'varinput', 'varlabel','traininput','trainlable');
                
                
            elseif strcmp(button,'Matlab')
                tn=num-n;
                trainData=cell(tn,2);
                for i=1:tn
                    ttemp(:,:,1)=traindata(:,:,i);
                    trainData{i,1}=ttemp;
                    ltemp(:,:,1)=labeldata(:,:,i);
                    trainData{i,2}=ltemp;
                end
                varData=cell(n,2);
                for i=1:n
                    ttemp(:,:,1)=varmaid(:,:,i);
                    varData{i,1}=ttemp;
                    ltemp(:,:,1)=varlabel(:,:,i);
                    varData{i,2}=ltemp;
                end
                testData=cell(ttn,2);
                for i=1:ttn
                    ttemp(:,:,1)=testdatain(:,:,i);
                    testData{i,1}=ttemp;
                    ltemp(:,:,1)=testlabel(:,:,i);
                    testData{i,2}=ltemp;
                end


                trainData=cell2table(trainData);
                varData=cell2table(varData);
                testData=cell2table(testData);
                trainData.Properties.VariableNames{2} = 'labelimg';
                varData.Properties.VariableNames{2} = 'labelimg';
                testData.Properties.VariableNames{2} = 'labelimg';
                
                indext=randperm(tn);
                trainSet=trainData(indext,:);
                indext=randperm(n);
                varSet=varData(indext,:);
                indext=randperm(ttn);
                testSet=testData(indext,:);
                
                prompt = {'指定训练集名称:','指定验证集名称:','指定测试集名称:'};
                dlgtitle = 'Input';
                dims = [1 35];
                definput = {'trainSet','varSet','testSet'};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                assignin('base',answer{1},trainSet);
                assignin('base',answer{2},varSet);
                assignin('base',answer{3},testSet);
            elseif strcmp(button,'取消')
                return;
            end
            
            
        end

        % Button pushed function: Bt_importbin
        function Bt_importbinButtonPushed(app, event)
            csvpath=uigetdir('D:\Work_F\CNNFyj','选择bin文件夹');
            if csvpath==0
                return;
            end
            app.csvdata=binreader(csvpath,app.EF_binFream.Value,[app.EF_x1.Value app.EF_x2.Value],[app.EF_y1.Value app.EF_y2.Value]);
            app.csvorg=app.csvdata;
            datasize=size(app.csvdata);
            app.num_total.Text=num2str(datasize(3));
            app.max_index=datasize(3);
            
            mesh(app.UIAxes,app.csvdata(:,:,1));
            mesh(app.UIAxes_face,app.csvdata(:,:,1));
            view(app.UIAxes_face,[0,-90]);
            app.num_now.Text=num2str(1);
            app.index=1;
            
            temp=app.csvdata(:,:,1);
            app.EF_maxValue.Text=num2str(max(max(temp)));
            app.EF_minValue.Text=num2str(min(min(temp)));
            app.EF_meanValue.Text=num2str(mean(mean(temp)));
            
            app.Bt_nextmesh.Enable = 1;
            app.Bt_filterSave.Enable=1;
            
            for n=1:app.max_index
                app.maxplot(end+1)=max(max(app.csvdata(:,:,n)));
                app.minplot(end+1)=min(min(app.csvdata(:,:,n)));
                app.meanplot(end+1)=mean(mean(app.csvdata(:,:,n)));
            end
            plot(app.UIAxes_plot,app.maxplot);
            hold(app.UIAxes_plot,'on');
            plot(app.UIAxes_plot,app.minplot);
            plot(app.UIAxes_plot,app.meanplot);
            hold(app.UIAxes_plot,'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '三维数据导入工具';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label_7 = uilabel(app.LeftPanel);
            app.Label_7.Text = '总数：';
            app.Bt_importdata = uibutton(app.LeftPanel, 'push');
            app.Bt_importdata.ButtonPushedFcn = createCallbackFcn(app, @Bt_importdataButtonPushed, true);
            app.Bt_importdata.Text = '导入CSV';
            app.Bt_perviousmesh = uibutton(app.LeftPanel, 'push');
            app.Bt_perviousmesh.ButtonPushedFcn = createCallbackFcn(app, @Bt_perviousmeshButtonPushed, true);
            app.Bt_perviousmesh.Text = '上一个';
            app.Bt_nextmesh = uibutton(app.LeftPanel, 'push');
            app.Bt_nextmesh.ButtonPushedFcn = createCallbackFcn(app, @Bt_nextmeshButtonPushed, true);
            app.Bt_nextmesh.Text = '下一个';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '当前:';
            app.num_now = uilabel(app.LeftPanel);
            app.num_now.Text = '0';
            app.num_total = uilabel(app.LeftPanel);
            app.num_total.Text = '0';
            app.EF_maxValue = uilabel(app.LeftPanel);
            app.EF_maxValue.Text = '0';
            app.EF_minValue = uilabel(app.LeftPanel);
            app.EF_minValue.Text = '0';
            app.EF_meanValue = uilabel(app.LeftPanel);
            app.EF_meanValue.Text = '0';
            app.Label_13 = uilabel(app.LeftPanel);
            app.Label_13.Text = '最大';
            app.fitlerMax = uieditfield(app.LeftPanel, 'numeric');
            app.fitlerMax.Value = 3.5;
            app.Bt_importdataWs = uibutton(app.LeftPanel, 'push');
            app.Bt_importdataWs.ButtonPushedFcn = createCallbackFcn(app, @Bt_importdataWsButtonPushed, true);
            app.Bt_importdataWs.Text = '导入mat';
            app.Bt_makepos = uibutton(app.LeftPanel, 'push');
            app.Bt_makepos.ButtonPushedFcn = createCallbackFcn(app, @Bt_makeposButtonPushed, true);
            app.Bt_makepos.Text = '定位';
            app.Label_17 = uilabel(app.LeftPanel);
            app.Label_17.Text = '操作面板';
            app.Bt_mark = uibutton(app.LeftPanel, 'push');
            app.Bt_mark.ButtonPushedFcn = createCallbackFcn(app, @Bt_markButtonPushed, true);
            app.Bt_mark.Text = '标记';
            app.fitlerMin = uieditfield(app.LeftPanel, 'numeric');
            app.fitlerMin.Value = 2.5;
            app.Label_14 = uilabel(app.LeftPanel);
            app.Label_14.Text = '最小';
            app.Label_15 = uilabel(app.LeftPanel);
            app.Label_15.Text = '均值';
            app.fitlerMean = uieditfield(app.LeftPanel, 'numeric');
            app.fitlerMean.Value = 3.35;
            app.Bt_filterSave = uibutton(app.LeftPanel, 'push');
            app.Bt_filterSave.ButtonPushedFcn = createCallbackFcn(app, @Bt_filterSaveButtonPushed, true);
            app.Bt_filterSave.Text = '滤波';
            app.Bt_plotvalue = uibutton(app.LeftPanel, 'push');
            app.Bt_plotvalue.ButtonPushedFcn = createCallbackFcn(app, @Bt_plotvalueButtonPushed, true);
            app.Bt_plotvalue.Text = '绘制';
            app.Bt_filterReset = uibutton(app.LeftPanel, 'push');
            app.Bt_filterReset.ButtonPushedFcn = createCallbackFcn(app, @Bt_filterResetButtonPushed, true);
            app.Bt_filterReset.Text = '重置';
            app.Bt_save2work = uibutton(app.LeftPanel, 'push');
            app.Bt_save2work.ButtonPushedFcn = createCallbackFcn(app, @Bt_save2workButtonPushed, true);
            app.Bt_save2work.Text = '保存';
            app.LB_marked = uilistbox(app.LeftPanel);
            app.LB_marked.Items = {};
            app.LB_marked.ValueChangedFcn = createCallbackFcn(app, @LB_markedValueChanged, true);
            app.LB_marked.Value = {};
            app.EF_x1 = uispinner(app.LeftPanel);
            app.EF_x1.Limits = [1 240];
            app.EF_x1.Value = 64;
            app.EF_x2 = uispinner(app.LeftPanel);
            app.EF_x2.Limits = [1 240];
            app.EF_x2.Value = 163;
            app.EF_y1 = uispinner(app.LeftPanel);
            app.EF_y1.Limits = [1 240];
            app.EF_y1.Value = 111;
            app.EF_y2 = uispinner(app.LeftPanel);
            app.EF_y2.Limits = [1 320];
            app.EF_y2.Value = 210;
            app.Bt_remark = uibutton(app.LeftPanel, 'push');
            app.Bt_remark.ButtonPushedFcn = createCallbackFcn(app, @Bt_remarkButtonPushed, true);
            app.Bt_remark.Text = '删除';
            app.Bt_removemarker = uibutton(app.LeftPanel, 'push');
            app.Bt_removemarker.ButtonPushedFcn = createCallbackFcn(app, @Bt_removemarkerButtonPushed, true);
            app.Bt_removemarker.Text = '去除';
            app.Bt_catdata = uibutton(app.LeftPanel, 'push');
            app.Bt_catdata.ButtonPushedFcn = createCallbackFcn(app, @Bt_catdataButtonPushed, true);
            app.Bt_catdata.Text = '合并';
            app.Bt_makeTraindata = uibutton(app.LeftPanel, 'push');
            app.Bt_makeTraindata.ButtonPushedFcn = createCallbackFcn(app, @Bt_makeTraindataButtonPushed, true);
            app.Bt_makeTraindata.Text = '数据集';
            app.CB_datarot = uicheckbox(app.LeftPanel);
            app.CB_datarot.Text = '旋转增强';
            app.Bt_importbin = uibutton(app.LeftPanel, 'push');
            app.Bt_importbin.ButtonPushedFcn = createCallbackFcn(app, @Bt_importbinButtonPushed, true);
            app.Bt_importbin.Text = '导入bin';
            app.EF_binFream = uispinner(app.LeftPanel);
            app.EF_binFream.Limits = [1 Inf];
            app.EF_binFream.Value = 230;
            app.pythonCB = uicheckbox(app.LeftPanel);
            app.pythonCB.Text = 'python';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes_face = uiaxes(app.GridLayout2);
            title(app.UIAxes_face, '正面图像')
            xlabel(app.UIAxes_face, 'X')
            ylabel(app.UIAxes_face, 'Y')
            zlabel(app.UIAxes_face, 'Z')
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, '深度数据')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_plot = uiaxes(app.GridLayout2);
            title(app.UIAxes_plot, '整体数据')
            xlabel(app.UIAxes_plot, 'X')
            ylabel(app.UIAxes_plot, 'Y')
            zlabel(app.UIAxes_plot, 'Z')
            app.Label_16 = uilabel(app.GridLayout2);
            app.Label_16.Text = '数据显示面板';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = batmesh

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end