classdef model < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Label_9         matlab.ui.control.Label
        Panel           matlab.ui.container.Panel
        Button_4        matlab.ui.control.Button
        Button_3        matlab.ui.control.Button
        selNum          matlab.ui.control.Spinner
        Label_8         matlab.ui.control.Label
        ButtonGroup     matlab.ui.container.ButtonGroup
        PSNRButton      matlab.ui.control.RadioButton
        MSEButton       matlab.ui.control.RadioButton
        SSIMButton      matlab.ui.control.RadioButton
        printImg_2      matlab.ui.control.Button
        BeforeLabel_5   matlab.ui.control.Label
        ImproveLabel_5  matlab.ui.control.Label
        SSIMLabel_15    matlab.ui.control.Label
        eva_ssim_e_5    matlab.ui.control.Label
        PSNRLabel_15    matlab.ui.control.Label
        MSELabel_15     matlab.ui.control.Label
        eva_mse_e_5     matlab.ui.control.Label
        eva_psnr_e_5    matlab.ui.control.Label
        AfterLabel_5    matlab.ui.control.Label
        PSNRLabel_14    matlab.ui.control.Label
        SSIMLabel_14    matlab.ui.control.Label
        MSELabel_14     matlab.ui.control.Label
        eva_ssim_a_5    matlab.ui.control.Label
        eva_mse_a_5     matlab.ui.control.Label
        eva_psnr_a_5    matlab.ui.control.Label
        ImproveLabel_4  matlab.ui.control.Label
        SSIMLabel_13    matlab.ui.control.Label
        eva_ssim_e_4    matlab.ui.control.Label
        PSNRLabel_13    matlab.ui.control.Label
        MSELabel_13     matlab.ui.control.Label
        eva_mse_e_4     matlab.ui.control.Label
        eva_psnr_e_4    matlab.ui.control.Label
        AfterLabel_4    matlab.ui.control.Label
        PSNRLabel_12    matlab.ui.control.Label
        SSIMLabel_12    matlab.ui.control.Label
        MSELabel_12     matlab.ui.control.Label
        eva_ssim_a_4    matlab.ui.control.Label
        eva_mse_a_4     matlab.ui.control.Label
        eva_psnr_a_4    matlab.ui.control.Label
        BeforeLabel_4   matlab.ui.control.Label
        AfterLabel_3    matlab.ui.control.Label
        ImproveLabel_3  matlab.ui.control.Label
        SSIMLabel_11    matlab.ui.control.Label
        eva_ssim_e_3    matlab.ui.control.Label
        PSNRLabel_11    matlab.ui.control.Label
        MSELabel_11     matlab.ui.control.Label
        eva_mse_e_3     matlab.ui.control.Label
        eva_psnr_e_3    matlab.ui.control.Label
        PSNRLabel_10    matlab.ui.control.Label
        SSIMLabel_10    matlab.ui.control.Label
        MSELabel_10     matlab.ui.control.Label
        eva_ssim_a_3    matlab.ui.control.Label
        eva_mse_a_3     matlab.ui.control.Label
        eva_psnr_a_3    matlab.ui.control.Label
        BeforeLabel_3   matlab.ui.control.Label
        BeforeLabel_2   matlab.ui.control.Label
        AfterLabel_2    matlab.ui.control.Label
        ImproveLabel_2  matlab.ui.control.Label
        SSIMLabel_9     matlab.ui.control.Label
        eva_ssim_e_2    matlab.ui.control.Label
        PSNRLabel_9     matlab.ui.control.Label
        MSELabel_9      matlab.ui.control.Label
        eva_mse_e_2     matlab.ui.control.Label
        eva_psnr_e_2    matlab.ui.control.Label
        PSNRLabel_8     matlab.ui.control.Label
        SSIMLabel_8     matlab.ui.control.Label
        MSELabel_8      matlab.ui.control.Label
        eva_ssim_a_2    matlab.ui.control.Label
        eva_mse_a_2     matlab.ui.control.Label
        eva_psnr_a_2    matlab.ui.control.Label
        ImproveLabel    matlab.ui.control.Label
        SSIMLabel_7     matlab.ui.control.Label
        eva_ssim_e_1    matlab.ui.control.Label
        PSNRLabel_7     matlab.ui.control.Label
        MSELabel_7      matlab.ui.control.Label
        eva_mse_e_1     matlab.ui.control.Label
        eva_psnr_e_1    matlab.ui.control.Label
        AfterLabel      matlab.ui.control.Label
        BeforeLabel     matlab.ui.control.Label
        PSNRLabel_6     matlab.ui.control.Label
        SSIMLabel_6     matlab.ui.control.Label
        MSELabel_6      matlab.ui.control.Label
        eva_ssim_a_1    matlab.ui.control.Label
        eva_mse_a_1     matlab.ui.control.Label
        eva_psnr_a_1    matlab.ui.control.Label
        eva_ssim_b_5    matlab.ui.control.Label
        eva_mse_b_5     matlab.ui.control.Label
        eva_psnr_b_5    matlab.ui.control.Label
        eva_ssim_b_4    matlab.ui.control.Label
        eva_mse_b_4     matlab.ui.control.Label
        eva_psnr_b_4    matlab.ui.control.Label
        eva_ssim_b_3    matlab.ui.control.Label
        eva_mse_b_3     matlab.ui.control.Label
        eva_psnr_b_3    matlab.ui.control.Label
        eva_ssim_b_2    matlab.ui.control.Label
        eva_mse_b_2     matlab.ui.control.Label
        eva_psnr_b_2    matlab.ui.control.Label
        eva_psnr_b_1    matlab.ui.control.Label
        eva_mse_b_1     matlab.ui.control.Label
        eva_ssim_b_1    matlab.ui.control.Label
        PSNRLabel_5     matlab.ui.control.Label
        PSNRLabel_4     matlab.ui.control.Label
        PSNRLabel_3     matlab.ui.control.Label
        PSNRLabel_2     matlab.ui.control.Label
        PSNRLabel       matlab.ui.control.Label
        MSELabel_5      matlab.ui.control.Label
        MSELabel_4      matlab.ui.control.Label
        MSELabel_3      matlab.ui.control.Label
        MSELabel_2      matlab.ui.control.Label
        MSELabel        matlab.ui.control.Label
        SSIMLabel_5     matlab.ui.control.Label
        SSIMLabel_4     matlab.ui.control.Label
        SSIMLabel_3     matlab.ui.control.Label
        SSIMLabel_2     matlab.ui.control.Label
        SSIMLabel       matlab.ui.control.Label
        TabGroup        matlab.ui.container.TabGroup
        MatlabTab       matlab.ui.container.Tab
        clearData       matlab.ui.control.Button
        importTestData  matlab.ui.control.Button
        draw3D          matlab.ui.control.Button
        ModelList       matlab.ui.control.ListBox
        Label           matlab.ui.control.Label
        clearModel      matlab.ui.control.Button
        importModel     matlab.ui.control.Button
        PythonTab       matlab.ui.container.Tab
        Button_2        matlab.ui.control.Button
        clearMaker      matlab.ui.control.Button
        selIndex        matlab.ui.control.Button
        Label_7         matlab.ui.control.Label
        selList         matlab.ui.control.ListBox
        printImg        matlab.ui.control.Button
        addSel_5        matlab.ui.control.Button
        addSel_4        matlab.ui.control.Button
        addSel_3        matlab.ui.control.Button
        addSel_2        matlab.ui.control.Button
        addSel_1        matlab.ui.control.Button
        Label_5         matlab.ui.control.Label
        Label_4         matlab.ui.control.Label
        PrvGroup        matlab.ui.control.Button
        NextGroup       matlab.ui.control.Button
        testDataNumNow  matlab.ui.control.Label
        Label_3         matlab.ui.control.Label
        testDataNum     matlab.ui.control.Label
        Label_2         matlab.ui.control.Label
        UIAxe_raw3d_5   matlab.ui.control.UIAxes
        UIAxe_out3d_5   matlab.ui.control.UIAxes
        UIAxe_true3d_5  matlab.ui.control.UIAxes
        UIAxe_raw3d_4   matlab.ui.control.UIAxes
        UIAxe_out3d_4   matlab.ui.control.UIAxes
        UIAxe_true3d_4  matlab.ui.control.UIAxes
        UIAxe_true3d_3  matlab.ui.control.UIAxes
        UIAxe_out3d_3   matlab.ui.control.UIAxes
        UIAxe_raw3d_3   matlab.ui.control.UIAxes
        UIAxe_true3d_2  matlab.ui.control.UIAxes
        UIAxe_out3d_2   matlab.ui.control.UIAxes
        UIAxe_raw3d_2   matlab.ui.control.UIAxes
        UIAxe_true3d_1  matlab.ui.control.UIAxes
        UIAxe_out3d_1   matlab.ui.control.UIAxes
        UIAxe_raw3d_1   matlab.ui.control.UIAxes
        UIAxe_out_5     matlab.ui.control.UIAxes
        UIAxe_out_4     matlab.ui.control.UIAxes
        UIAxe_out_3     matlab.ui.control.UIAxes
        UIAxe_out_2     matlab.ui.control.UIAxes
        UIAxe_out_1     matlab.ui.control.UIAxes
        UIAxe_raw_5     matlab.ui.control.UIAxes
        UIAxe_raw_4     matlab.ui.control.UIAxes
        UIAxe_raw_3     matlab.ui.control.UIAxes
        UIAxe_raw_2     matlab.ui.control.UIAxes
        UIAxe_raw_1     matlab.ui.control.UIAxes
        UIAxe_true_5    matlab.ui.control.UIAxes
        UIAxe_true_4    matlab.ui.control.UIAxes
        UIAxe_true_3    matlab.ui.control.UIAxes
        UIAxe_true_2    matlab.ui.control.UIAxes
        UIAxe_true_1    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        modelSelNow %当前选择的模型
        testdata %测试数据，是一个表格
        dataIndex %当前显示的数据
        datanum %测试数据量
        maker
        alleva_f
        
        plotssim_a
        plotmse_a
        plotpsnr_a
    
        plotssim_b
        plotmse_b
        plotpsnr_b

        labelData_py
        rawData_py
        restoreData_py
    end
    
    properties (Access = private)
        ex_model % Description
    end
    
    methods (Access = private)
        
        function results = updataMash(app,dataindex)
            %输入当前索引，刷新后索引要更改，还要判断是否还够5个
            %显示真实图
            if app.datanum-dataindex<5
                dataindex=app.datanum-4;
            end
            results=dataindex+5;
            %if app.TabGroup.SelectedTab == "Matlab"
                
                %真实图像——正面
                mesh(app.UIAxe_true_1,app.testdata{dataindex,2}{1});
                view(app.UIAxe_true_1,[0,-90]);
                colormap(app.UIAxe_true_1,"turbo");
                mesh(app.UIAxe_true_2,app.testdata{dataindex+1,2}{1});
                view(app.UIAxe_true_2,[0,-90]);
                colormap(app.UIAxe_true_2,"turbo");
                mesh(app.UIAxe_true_3,app.testdata{dataindex+2,2}{1});
                view(app.UIAxe_true_3,[0,-90]);
                colormap(app.UIAxe_true_3,"turbo");
                mesh(app.UIAxe_true_4,app.testdata{dataindex+3,2}{1});
                view(app.UIAxe_true_4,[0,-90]);
                colormap(app.UIAxe_true_4,"turbo");
                mesh(app.UIAxe_true_5,app.testdata{dataindex+4,2}{1});
                view(app.UIAxe_true_5,[0,-90]);
                colormap(app.UIAxe_true_5,"turbo");
                %真实图像——角度
                mesh(app.UIAxe_true3d_1,app.testdata{dataindex,2}{1});
                view(app.UIAxe_true3d_1,[45,45]);
                app.UIAxe_true3d_1.ZDir = 'reverse';
                colormap(app.UIAxe_true3d_1,"turbo");
                mesh(app.UIAxe_true3d_2,app.testdata{dataindex+1,2}{1});
                view(app.UIAxe_true3d_2,[45,45]);
                app.UIAxe_true3d_2.ZDir = 'reverse';
                colormap(app.UIAxe_true3d_2,"turbo");
                mesh(app.UIAxe_true3d_3,app.testdata{dataindex+2,2}{1});
                view(app.UIAxe_true3d_3,[45,45]);
                app.UIAxe_true3d_3.ZDir = 'reverse';
                colormap(app.UIAxe_true3d_3,"turbo");
                mesh(app.UIAxe_true3d_4,app.testdata{dataindex+3,2}{1});
                view(app.UIAxe_true3d_4,[45,45]);
                app.UIAxe_true3d_4.ZDir = 'reverse';
                colormap(app.UIAxe_true3d_4,"turbo");
                mesh(app.UIAxe_true3d_5,app.testdata{dataindex+4,2}{1});
                view(app.UIAxe_true3d_5,[45,45]);
                app.UIAxe_true3d_5.ZDir = 'reverse';
                colormap(app.UIAxe_true3d_5,"turbo");
                

                %显示原始图_正面
                mesh(app.UIAxe_raw_1,app.testdata{dataindex,1}{1});
                view(app.UIAxe_raw_1,[0,-90]);
                colormap(app.UIAxe_raw_1,"turbo");
                mesh(app.UIAxe_raw_2,app.testdata{dataindex+1,1}{1});
                view(app.UIAxe_raw_2,[0,-90]);
                colormap(app.UIAxe_raw_2,"turbo");
                mesh(app.UIAxe_raw_3,app.testdata{dataindex+2,1}{1});
                view(app.UIAxe_raw_3,[0,-90]);
                colormap(app.UIAxe_raw_3,"turbo");
                mesh(app.UIAxe_raw_4,app.testdata{dataindex+3,1}{1});
                view(app.UIAxe_raw_4,[0,-90]);
                colormap(app.UIAxe_raw_4,"turbo");
                mesh(app.UIAxe_raw_5,app.testdata{dataindex+4,1}{1});
                view(app.UIAxe_raw_5,[0,-90]);
                colormap(app.UIAxe_raw_5,"turbo");

                %显示原始图——角度面
                mesh(app.UIAxe_raw3d_1,app.testdata{dataindex,1}{1});
                view(app.UIAxe_raw3d_1,[45,45]);
                app.UIAxe_raw3d_1.ZDir = 'reverse';
                colormap(app.UIAxe_raw3d_1,"turbo");
                mesh(app.UIAxe_raw3d_2,app.testdata{dataindex+1,1}{1});
                view(app.UIAxe_raw3d_2,[45,45]);
                app.UIAxe_raw3d_2.ZDir = 'reverse';
                colormap(app.UIAxe_raw3d_2,"turbo");
                mesh(app.UIAxe_raw3d_3,app.testdata{dataindex+2,1}{1});
                view(app.UIAxe_raw3d_3,[45,45]);
                app.UIAxe_raw3d_3.ZDir = 'reverse';
                colormap(app.UIAxe_raw3d_3,"turbo");
                mesh(app.UIAxe_raw3d_4,app.testdata{dataindex+3,1}{1});
                view(app.UIAxe_raw3d_4,[45,45]);
                app.UIAxe_raw3d_4.ZDir = 'reverse';
                colormap(app.UIAxe_raw3d_4,"turbo");
                mesh(app.UIAxe_raw3d_5,app.testdata{dataindex+4,1}{1});
                view(app.UIAxe_raw3d_5,[45,45]);
                app.UIAxe_raw3d_5.ZDir = 'reverse';
                colormap(app.UIAxe_raw3d_5,"turbo");


                %显示重构图_正面
                mesh(app.UIAxe_out_1,app.modelSelNow.predict(app.testdata{dataindex,1}{1}));
                view(app.UIAxe_out_1,[0,-90]);
                colormap(app.UIAxe_out_1,"turbo");
                mesh(app.UIAxe_out_2,app.modelSelNow.predict(app.testdata{dataindex+1,1}{1}));
                view(app.UIAxe_out_2,[0,-90]);
                colormap(app.UIAxe_out_2,"turbo");
                mesh(app.UIAxe_out_3,app.modelSelNow.predict(app.testdata{dataindex+2,1}{1}));
                view(app.UIAxe_out_3,[0,-90]);
                colormap(app.UIAxe_out_3,"turbo");
                mesh(app.UIAxe_out_4,app.modelSelNow.predict(app.testdata{dataindex+3,1}{1}));
                view(app.UIAxe_out_4,[0,-90]);
                colormap(app.UIAxe_out_4,"turbo");
                mesh(app.UIAxe_out_5,app.modelSelNow.predict(app.testdata{dataindex+4,1}{1}));
                view(app.UIAxe_out_5,[0,-90]);
                colormap(app.UIAxe_out_5,"turbo");
                %显示重构图——角度
                mesh(app.UIAxe_out3d_1,app.modelSelNow.predict(app.testdata{dataindex,1}{1}));
                view(app.UIAxe_out3d_1,[45,45]);
                app.UIAxe_out3d_1.ZDir = 'reverse';
                colormap(app.UIAxe_out3d_1,"turbo");
                mesh(app.UIAxe_out3d_2,app.modelSelNow.predict(app.testdata{dataindex+1,1}{1}));
                view(app.UIAxe_out3d_2,[45,45]);
                app.UIAxe_out3d_2.ZDir = 'reverse';
                colormap(app.UIAxe_out3d_2,"turbo");
                mesh(app.UIAxe_out3d_3,app.modelSelNow.predict(app.testdata{dataindex+2,1}{1}));
                view(app.UIAxe_out3d_3,[45,45]);
                app.UIAxe_out3d_3.ZDir = 'reverse';
                colormap(app.UIAxe_out3d_3,"turbo");
                mesh(app.UIAxe_out3d_4,app.modelSelNow.predict(app.testdata{dataindex+3,1}{1}));
                view(app.UIAxe_out3d_4,[45,45]);
                app.UIAxe_out3d_4.ZDir = 'reverse';
                colormap(app.UIAxe_out3d_4,"turbo");
                mesh(app.UIAxe_out3d_5,app.modelSelNow.predict(app.testdata{dataindex+4,1}{1}));
                view(app.UIAxe_out3d_5,[45,45]);
                app.UIAxe_out3d_5.ZDir = 'reverse';
                colormap(app.UIAxe_out3d_5,"turbo"); 
                
                bevashow=zeros(3,1);
                aevashow=zeros(3,1);
                [m, n] = size(app.testdata{dataindex,2}{1});%真实图像尺寸
                [p, q] = size(app.testdata{dataindex,1}{1});%原始图像尺寸，模糊的图像有可能大于标签
 
                % 比较矩阵的维度是否相同
                if m == p && n == q
                    %尺寸相同
                    [bevashow(1,1), bevashow(2,1),bevashow(3,1)]=app.updataeva(app.testdata{dataindex,1}{1},app.testdata{dataindex,2}{1});
                    app.eva_ssim_b_1.Text=num2str(bevashow(1));
                    app.eva_mse_b_1.Text=num2str(bevashow(2));
                    app.eva_psnr_b_1.Text=num2str(bevashow(3));
                    [aevashow(1,1), aevashow(2,1),aevashow(3,1)]=app.updataeva(double(app.modelSelNow.predict(app.testdata{dataindex,1}{1})),app.testdata{dataindex,2}{1});
                    app.eva_ssim_a_1.Text=num2str(aevashow(1));
                    app.eva_mse_a_1.Text=num2str(aevashow(2));
                    app.eva_psnr_a_1.Text=num2str(aevashow(3));
                    app.eva_ssim_e_1.Text=num2str(aevashow(1)-bevashow(1));
                    app.eva_mse_e_1.Text=num2str(aevashow(2)-bevashow(2));
                    app.eva_psnr_e_1.Text=num2str(aevashow(3)-bevashow(3));
    
                    [bevashow(1,1), bevashow(2,1),bevashow(3,1)]=app.updataeva(app.testdata{dataindex+1,1}{1},app.testdata{dataindex+1,2}{1});
                    app.eva_ssim_b_2.Text=num2str(bevashow(1));
                    app.eva_mse_b_2.Text=num2str(bevashow(2));
                    app.eva_psnr_b_2.Text=num2str(bevashow(3));
                    [aevashow(1,1), aevashow(2,1),aevashow(3,1)]=app.updataeva(double(app.modelSelNow.predict(app.testdata{dataindex+1,1}{1})),app.testdata{dataindex+1,2}{1});
                    app.eva_ssim_a_2.Text=num2str(aevashow(1));
                    app.eva_mse_a_2.Text=num2str(aevashow(2));
                    app.eva_psnr_a_2.Text=num2str(aevashow(3));
                    app.eva_ssim_e_2.Text=num2str(aevashow(1)-bevashow(1));
                    app.eva_mse_e_2.Text=num2str(aevashow(2)-bevashow(2));
                    app.eva_psnr_e_2.Text=num2str(aevashow(3)-bevashow(3));
    
                    [bevashow(1,1), bevashow(2,1),bevashow(3,1)]=app.updataeva(app.testdata{dataindex+2,1}{1},app.testdata{dataindex+2,2}{1});
                    app.eva_ssim_b_3.Text=num2str(bevashow(1));
                    app.eva_mse_b_3.Text=num2str(bevashow(2));
                    app.eva_psnr_b_3.Text=num2str(bevashow(3));
                    [aevashow(1,1), aevashow(2,1),aevashow(3,1)]=app.updataeva(double(app.modelSelNow.predict(app.testdata{dataindex+2,1}{1})),app.testdata{dataindex+2,2}{1});
                    app.eva_ssim_a_3.Text=num2str(aevashow(1));
                    app.eva_mse_a_3.Text=num2str(aevashow(2));
                    app.eva_psnr_a_3.Text=num2str(aevashow(3));
                    app.eva_ssim_e_3.Text=num2str(aevashow(1)-bevashow(1));
                    app.eva_mse_e_3.Text=num2str(aevashow(2)-bevashow(2));
                    app.eva_psnr_e_3.Text=num2str(aevashow(3)-bevashow(3));
    
                    [bevashow(1,1), bevashow(2,1),bevashow(3,1)]=app.updataeva(app.testdata{dataindex+3,1}{1},app.testdata{dataindex+3,2}{1});
                    app.eva_ssim_b_4.Text=num2str(bevashow(1));
                    app.eva_mse_b_4.Text=num2str(bevashow(2));
                    app.eva_psnr_b_4.Text=num2str(bevashow(3));
                    [aevashow(1,1), aevashow(2,1),aevashow(3,1)]=app.updataeva(double(app.modelSelNow.predict(app.testdata{dataindex+3,1}{1})),app.testdata{dataindex+3,2}{1});
                    app.eva_ssim_a_4.Text=num2str(aevashow(1));
                    app.eva_mse_a_4.Text=num2str(aevashow(2));
                    app.eva_psnr_a_4.Text=num2str(aevashow(3));
                    app.eva_ssim_e_4.Text=num2str(aevashow(1)-bevashow(1));
                    app.eva_mse_e_4.Text=num2str(aevashow(2)-bevashow(2));
                    app.eva_psnr_e_4.Text=num2str(aevashow(3)-bevashow(3));
    
                    [bevashow(1,1), bevashow(2,1),bevashow(3,1)]=app.updataeva(app.testdata{dataindex+4,1}{1},app.testdata{dataindex+4,2}{1});
                    app.eva_ssim_b_5.Text=num2str(bevashow(1));
                    app.eva_mse_b_5.Text=num2str(bevashow(2));
                    app.eva_psnr_b_5.Text=num2str(bevashow(3));
                    [aevashow(1,1), aevashow(2,1),aevashow(3,1)]=app.updataeva(double(app.modelSelNow.predict(app.testdata{dataindex+4,1}{1})),app.testdata{dataindex+4,2}{1});
                    app.eva_ssim_a_5.Text=num2str(aevashow(1));
                    app.eva_mse_a_5.Text=num2str(aevashow(2));
                    app.eva_psnr_a_5.Text=num2str(aevashow(3));
                    app.eva_ssim_e_5.Text=num2str(aevashow(1)-bevashow(1));
                    app.eva_mse_e_5.Text=num2str(aevashow(2)-bevashow(2));
                    app.eva_psnr_e_5.Text=num2str(aevashow(3)-bevashow(3));
                else
                    %尺寸不同
                    big=app.testdata{dataindex,1}{1};%模糊                    
                    raw=big(10:(m-1)+10,10:(n-1)+10);
                    [bevashow(1,1), bevashow(2,1),bevashow(3,1)]=app.updataeva(raw,app.testdata{dataindex,2}{1});
                    app.eva_ssim_b_1.Text=num2str(bevashow(1));
                    app.eva_mse_b_1.Text=num2str(bevashow(2));
                    app.eva_psnr_b_1.Text=num2str(bevashow(3));
                    [aevashow(1,1), aevashow(2,1),aevashow(3,1)]=app.updataeva(double(app.modelSelNow.predict(app.testdata{dataindex,1}{1})),app.testdata{dataindex,2}{1});
                    app.eva_ssim_a_1.Text=num2str(aevashow(1));
                    app.eva_mse_a_1.Text=num2str(aevashow(2));
                    app.eva_psnr_a_1.Text=num2str(aevashow(3));
                    app.eva_ssim_e_1.Text=num2str(aevashow(1)-bevashow(1));
                    app.eva_mse_e_1.Text=num2str(aevashow(2)-bevashow(2));
                    app.eva_psnr_e_1.Text=num2str(aevashow(3)-bevashow(3));
    
                    big=app.testdata{dataindex+1,1}{1};%模糊                    
                    raw=big(10:(m-1)+10,10:(n-1)+10);
                    [bevashow(1,1), bevashow(2,1),bevashow(3,1)]=app.updataeva(raw,app.testdata{dataindex+1,2}{1});
                    app.eva_ssim_b_2.Text=num2str(bevashow(1));
                    app.eva_mse_b_2.Text=num2str(bevashow(2));
                    app.eva_psnr_b_2.Text=num2str(bevashow(3));
                    [aevashow(1,1), aevashow(2,1),aevashow(3,1)]=app.updataeva(double(app.modelSelNow.predict(app.testdata{dataindex+1,1}{1})),app.testdata{dataindex+1,2}{1});
                    app.eva_ssim_a_2.Text=num2str(aevashow(1));
                    app.eva_mse_a_2.Text=num2str(aevashow(2));
                    app.eva_psnr_a_2.Text=num2str(aevashow(3));
                    app.eva_ssim_e_2.Text=num2str(aevashow(1)-bevashow(1));
                    app.eva_mse_e_2.Text=num2str(aevashow(2)-bevashow(2));
                    app.eva_psnr_e_2.Text=num2str(aevashow(3)-bevashow(3));
    
                    big=app.testdata{dataindex+2,1}{1};%模糊                    
                    raw=big(10:(m-1)+10,10:(n-1)+10);
                    [bevashow(1,1), bevashow(2,1),bevashow(3,1)]=app.updataeva(raw,app.testdata{dataindex+2,2}{1});
                    app.eva_ssim_b_3.Text=num2str(bevashow(1));
                    app.eva_mse_b_3.Text=num2str(bevashow(2));
                    app.eva_psnr_b_3.Text=num2str(bevashow(3));
                    [aevashow(1,1), aevashow(2,1),aevashow(3,1)]=app.updataeva(double(app.modelSelNow.predict(app.testdata{dataindex+2,1}{1})),app.testdata{dataindex+2,2}{1});
                    app.eva_ssim_a_3.Text=num2str(aevashow(1));
                    app.eva_mse_a_3.Text=num2str(aevashow(2));
                    app.eva_psnr_a_3.Text=num2str(aevashow(3));
                    app.eva_ssim_e_3.Text=num2str(aevashow(1)-bevashow(1));
                    app.eva_mse_e_3.Text=num2str(aevashow(2)-bevashow(2));
                    app.eva_psnr_e_3.Text=num2str(aevashow(3)-bevashow(3));
    
                    big=app.testdata{dataindex+3,1}{1};%模糊                    
                    raw=big(10:(m-1)+10,10:(n-1)+10);
                    [bevashow(1,1), bevashow(2,1),bevashow(3,1)]=app.updataeva(raw,app.testdata{dataindex+3,2}{1});
                    app.eva_ssim_b_4.Text=num2str(bevashow(1));
                    app.eva_mse_b_4.Text=num2str(bevashow(2));
                    app.eva_psnr_b_4.Text=num2str(bevashow(3));
                    [aevashow(1,1), aevashow(2,1),aevashow(3,1)]=app.updataeva(double(app.modelSelNow.predict(app.testdata{dataindex+3,1}{1})),app.testdata{dataindex+3,2}{1});
                    app.eva_ssim_a_4.Text=num2str(aevashow(1));
                    app.eva_mse_a_4.Text=num2str(aevashow(2));
                    app.eva_psnr_a_4.Text=num2str(aevashow(3));
                    app.eva_ssim_e_4.Text=num2str(aevashow(1)-bevashow(1));
                    app.eva_mse_e_4.Text=num2str(aevashow(2)-bevashow(2));
                    app.eva_psnr_e_4.Text=num2str(aevashow(3)-bevashow(3));
    
                    big=app.testdata{dataindex+4,1}{1};%模糊                    
                    raw=big(10:(m-1)+10,10:(n-1)+10);
                    [bevashow(1,1), bevashow(2,1),bevashow(3,1)]=app.updataeva(raw,app.testdata{dataindex+4,2}{1});
                    app.eva_ssim_b_5.Text=num2str(bevashow(1));
                    app.eva_mse_b_5.Text=num2str(bevashow(2));
                    app.eva_psnr_b_5.Text=num2str(bevashow(3));
                    [aevashow(1,1), aevashow(2,1),aevashow(3,1)]=app.updataeva(double(app.modelSelNow.predict(app.testdata{dataindex+4,1}{1})),app.testdata{dataindex+4,2}{1});
                    app.eva_ssim_a_5.Text=num2str(aevashow(1));
                    app.eva_mse_a_5.Text=num2str(aevashow(2));
                    app.eva_psnr_a_5.Text=num2str(aevashow(3));
                    app.eva_ssim_e_5.Text=num2str(aevashow(1)-bevashow(1));
                    app.eva_mse_e_5.Text=num2str(aevashow(2)-bevashow(2));
                    app.eva_psnr_e_5.Text=num2str(aevashow(3)-bevashow(3));
                end
                
                app.testDataNumNow.Text=num2str(dataindex+5);
                
%                 return;
%             elseif app.TabGroup.SelectedTab == "Python"
%                 results=dataindex+5;
%                 mesh(app.UIAxe_true_1,app.labelData_py(:,:,dataindex));
%                 view(app.UIAxe_true_1,[0,-90]);
%                 colormap(app.UIAxe_true_1,"turbo");
%                 mesh(app.UIAxe_true_2,app.labelData_py(:,:,dataindex+1));
%                 view(app.UIAxe_true_2,[0,-90]);
%                 colormap(app.UIAxe_true_2,"turbo");
%                 mesh(app.UIAxe_true_3,app.labelData_py(:,:,dataindex+2));
%                 view(app.UIAxe_true_3,[0,-90]);
%                 colormap(app.UIAxe_true_3,"turbo");
%                 mesh(app.UIAxe_true_4,app.labelData_py(:,:,dataindex+3));
%                 view(app.UIAxe_true_4,[0,-90]);
%                 colormap(app.UIAxe_true_4,"turbo");
%                 mesh(app.UIAxe_true_5,app.labelData_py(:,:,dataindex+4));
%                 view(app.UIAxe_true_5,[0,-90]);
%                 colormap(app.UIAxe_true_5,"turbo");
%                 
%                 显示原始图
%                 mesh(app.UIAxe_raw_1,app.rawData_py(:,:,dataindex));
%                 view(app.UIAxe_raw_1,[0,-90]);
%                 colormap(app.UIAxe_raw_1,"turbo");
%                 mesh(app.UIAxe_raw_2,app.rawData_py(:,:,dataindex+1));
%                 view(app.UIAxe_raw_2,[0,-90]);
%                 colormap(app.UIAxe_raw_2,"turbo");
%                 mesh(app.UIAxe_raw_3,app.rawData_py(:,:,dataindex+2));
%                 view(app.UIAxe_raw_3,[0,-90]);
%                 colormap(app.UIAxe_raw_3,"turbo");
%                 mesh(app.UIAxe_raw_4,app.rawData_py(:,:,dataindex+3));
%                 view(app.UIAxe_raw_4,[0,-90]);
%                 colormap(app.UIAxe_raw_4,"turbo");
%                 mesh(app.UIAxe_raw_5,app.rawData_py(:,:,dataindex+4));
%                 view(app.UIAxe_raw_5,[0,-90]);
%                 colormap(app.UIAxe_raw_5,"turbo");
%                 
%                 显示重构图
%                 mesh(app.UIAxe_out_1,app.restoreData_py(:,:,dataindex));
%                 view(app.UIAxe_out_1,[0,-90]);
%                 colormap(app.UIAxe_out_1,"turbo");
%                 mesh(app.UIAxe_out_2,app.restoreData_py(:,:,dataindex+1));
%                 view(app.UIAxe_out_2,[0,-90]);
%                 colormap(app.UIAxe_out_2,"turbo");
%                 mesh(app.UIAxe_out_3,app.restoreData_py(:,:,dataindex+2));
%                 view(app.UIAxe_out_3,[0,-90]);
%                 colormap(app.UIAxe_out_3,"turbo");
%                 mesh(app.UIAxe_out_4,app.restoreData_py(:,:,dataindex+3));
%                 view(app.UIAxe_out_4,[0,-90]);
%                 colormap(app.UIAxe_out_4,"turbo");
%                 mesh(app.UIAxe_out_5,app.restoreData_py(:,:,dataindex+4));
%                 view(app.UIAxe_out_5,[0,-90]);
%                 colormap(app.UIAxe_out_5,"turbo");
%                 
%                 app.testDataNumNow.Text=num2str(dataindex+5);
%                 return;
%             end


        end
        
        function results = addmaker(app,btn,dataindex)
            %输入按钮号，输入当前索引，返回maker
            %当前索引是显示结果的最大+1
            switch (btn)
                case 1
                    selmaker=dataindex-5;
                case 2
                    selmaker=dataindex-4;
                case 3
                    selmaker=dataindex-3;
                case 4
                    selmaker=dataindex-2;
                case 5
                    selmaker=dataindex-1;
                case 6
                    selmaker=dataindex;
            end
            
            %如果里面没有重复的再加入
            %if isempty(find(app.maker==selmaker))
                app.selList.Items{end+1}=num2str(selmaker);%不论里面有没有都可以在后面加一个；
                results=selmaker;
            %end
            %results=-1;
        end
        
        function results = makename(~,path,makernum,flag)
            %flag，0清晰图，1原始图，2重构图
            switch (flag)
                case 0
                    results=strcat(path,{'\clear_'},num2str(makernum),'.tif');
                case 1
                    results=strcat(path,{'\raw_'},num2str(makernum),'.tif');
                case 2
                    results=strcat(path,{'\restore_'},num2str(makernum),'.tif');
                case 3
                    results=strcat(path,{'\clear3d_'},num2str(makernum),'.tif');
                case 4
                    results=strcat(path,{'\raw3d_'},num2str(makernum),'.tif');
                case 5
                    results=strcat(path,{'\restore3d_'},num2str(makernum),'.tif');
            end
            return;
        end
        
        function results = printimg(app,path,dataindex,flag)
            h=figure('visible','off');
            ax=axes;
            %先打印平面的
            switch (flag)
                case 0%清晰图
                    mesh(ax,app.testdata{dataindex,2}{1});
                case 1%模糊图
                    mesh(ax,app.testdata{dataindex,1}{1});
                case 2%重构图
                    mesh(ax,app.modelSelNow.predict(app.testdata{dataindex,1}{1}));
            end
            colorbar;
            set(gca,'FontSize',22);
            view(0,-90);
            colormap(h, 'turbo');
            patht=app.makename(path,num2str(dataindex),flag);
            print(h,patht{1},'-dtiffn','-r600');

            view(45,45);
            ax.ZDir = 'reverse';
            patht=app.makename(path,num2str(dataindex),flag+3);
            print(h,patht{1},'-dtiffn','-r600');

            results=0;


        end
        
        function results = updataMashOne(app,aix1,aix2,aix3,dataindex)

            results=0;
            mesh(aix1,app.testdata{dataindex,2}{1});
            view(aix1,[0,-90]);
            colormap(aix1,"turbo");
            
            %显示原始图
            mesh(aix2,app.testdata{dataindex,1}{1});
            view(aix2,[0,-90]);
            colormap(aix2,"turbo");
            
            
            %显示重构图
            mesh(aix3,app.modelSelNow.predict(app.testdata{dataindex,1}{1}));
            view(aix3,[0,-90]);
            colormap(aix3,"turbo");
            
        end
        
        function [ssvar, lovar, pevar] = updataeva(~,A,ref)
            ssvar = ssim(A,ref);%ssim
            lovar = mse(A,ref);%mse
            pevar = psnr(A,ref);%psnr

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.modelSelNow=0;%当前选中的模型
            app.testdata=0;%测试数据，是一个表格
            app.dataIndex=1;
            app.datanum=0;
            app.maker=[];
            app.alleva_f=0;%初始化评价参数，是否运行过所有参数计算回调函数，0没运行，1运行过

        end

        % Button pushed function: importModel
        function importModelPushed(app, event)
            val=evalin('base', 'who');
            sel=listdlg("ListString",val, ...
                    "Name","选择工作区中的模型", ...
                    'ListSize',[300,150],...
                    "OKString","导入", ...
                    "CancelString","取消");
            if isempty(sel)
                return;
            end
            k=val(sel);
            %选好的模型放在list列表框里
            nowlist=app.ModelList.Items;
            if isempty(nowlist)
                app.ModelList.Items=k;
            else
                temp=app.ModelList.Items;
                app.ModelList.Items=[temp k'];
            end
            app.ModelList.Value=app.ModelList.Items(1);
            app.modelSelNow=evalin('base',app.ModelList.Items{1});
        end

        % Button pushed function: clearModel
        function clearModelButtonPushed(app, event)
            app.ModelList.Items={};
        end

        % Value changed function: ModelList
        function ModelListValueChanged(app, event)
            value = app.ModelList.Value;
            app.modelSelNow=evalin('base',value);
        end

        % Button pushed function: draw3D
        function draw3DButtonPushed(app, event)
            if app.testDataNum==0 || isempty(app.ModelList.Items)
                msgbox('需要导入数据或模型。','消息提示',"modal");
                return;
            end
            app.dataIndex=app.dataIndex-5;
            
            app.dataIndex=app.updataMash(app.dataIndex);
            app.testDataNumNow.Text=num2str(app.dataIndex);
            
        end

        % Button pushed function: importTestData
        function importTestDataButtonPushed(app, event)
            %只能导入一个表，由另一个软件做的
            val=evalin('base', 'who');
            sel=listdlg("ListString",val, ...
                    "Name","选择工作区中的模型", ...
                    'ListSize',[300,150],...
                    "OKString","导入", ...
                    "CancelString","取消");
            if isempty(sel)
                return;
            end
            app.testdata=evalin('base',val{sel});
            temp=size(app.testdata);
            
            app.testDataNum.Text=num2str(temp(1));%数据总数
            app.datanum=temp(1);
            app.testDataNumNow.Text=num2str(1);%显示当前的索引
            app.dataIndex=1;%当前的索引
            app.alleva_f=0;%新数据导入后，评价函数要重新算
            app.dataIndex=app.updataMash(app.dataIndex);

            app.plotssim_a=zeros(temp(1),1);
            app.plotmse_a=zeros(temp(1),1);
            app.plotpsnr_a=zeros(temp(1),1);

            app.plotssim_b=zeros(temp(1),1);
            app.plotmse_b=zeros(temp(1),1);
            app.plotpsnr_b=zeros(temp(1),1);
            
        end

        % Button pushed function: NextGroup
        function NextGroupButtonPushed(app, event)
            if app.dataIndex>app.testDataNum %index会指向下一个，如果比最大值大一个，说明到最后了
                app.dataIndex=app.testDataNum-4;
            end
            app.dataIndex=app.updataMash(app.dataIndex);
        end

        % Button pushed function: PrvGroup
        function PrvGroupButtonPushed(app, event)
            if app.dataIndex>app.testDataNum
                app.dataIndex=app.dataIndex-5-mod(app.dataIndex,5);
            elseif app.dataIndex==6
                app.dataIndex=1;
            else
                app.dataIndex=app.dataIndex-10;
            end
            app.dataIndex=app.updataMash(app.dataIndex);
        end

        % Button pushed function: selIndex
        function selIndexButtonPushed(app, event)
            prompt = {'跳转到第几个数据:'};
            dlgtitle = 'Input';
            dims = [1 5];
            definput = {'0'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if isempty(answer)
                return;
            end
            num=str2double(answer{1});
            if num>app.testDataNum || num<0
                msgbox('超出了选取范围。','消息提示',"modal");
                return;
            end

            app.dataIndex=num;
            app.dataIndex=app.updataMash(app.dataIndex);
        end

        % Button pushed function: addSel_1
        function addSel_1ButtonPushed(app, event)
            %temp=app.addmaker(1,app.dataIndex);
            %if temp~=-1
                app.maker(end+1)=app.addmaker(1,app.dataIndex);
            %end
        end

        % Button pushed function: addSel_2
        function addSel_2ButtonPushed(app, event)
            app.maker(end+1)=app.addmaker(2,app.dataIndex);
        end

        % Button pushed function: addSel_3
        function addSel_3ButtonPushed(app, event)
            app.maker(end+1)=app.addmaker(3,app.dataIndex);
        end

        % Button pushed function: addSel_4
        function addSel_4ButtonPushed(app, event)
            app.maker(end+1)=app.addmaker(4,app.dataIndex);
        end

        % Button pushed function: addSel_5
        function addSel_5ButtonPushed(app, event)
            app.maker(end+1)=app.addmaker(5,app.dataIndex);
        end

        % Button pushed function: clearMaker
        function clearMakerButtonPushed(app, event)
            app.maker=[];
            app.selList.Items={};
        end

        % Button pushed function: printImg
        function printImgButtonPushed(app, event)
            imgsavepath=uigetdir('D:\Work_F\CNNFyj','选择保存文件夹');
            if imgsavepath==0
                return;
            end
            
            for i=1:length(app.maker)
                %保存清晰图
                app.printimg(imgsavepath,app.maker(i),0);
                %保存原始图
                app.printimg(imgsavepath,app.maker(i),1);
                %保存修正图 
                app.printimg(imgsavepath,app.maker(i),2);
            end

            
        end

        % Button pushed function: printImg_2
        function showAllevaButtonPushed(app, event)
            if app.alleva_f
                return;
            else
                app.alleva_f=1;
    
                [m, n] = size(app.testdata{1,2}{1});%真实图像尺寸
                [p, q] = size(app.testdata{1,1}{1});%原始图像尺寸，模糊的图像有可能大于标签
                w = waitbar(0,'0%','Name','正在处理...');
                if m == p && n == q
                    %尺寸相同
                    for i=1:app.datanum
                        [app.plotssim_b(i,1), app.plotmse_b(i,1),app.plotpsnr_b(i,1)]=app.updataeva(app.testdata{i,1}{1},app.testdata{i,2}{1});
                        [app.plotssim_a(i,1), app.plotmse_a(i,1),app.plotpsnr_a(i,1)]=app.updataeva(double(app.modelSelNow.predict(app.testdata{i,1}{1})),app.testdata{i,2}{1});
                        waitbar(i/app.datanum,w,sprintf('%d / %d',i,app.datanum));
                    end
                else
                    %尺寸不同
                    for i=1:app.datanum
                        big=app.testdata{i,1}{1};%模糊                    
                        raw=big(10:(m-1)+10,10:(n-1)+10);
                        [app.plotssim_b(i,1), app.plotmse_b(i,1),app.plotpsnr_b(i,1)]=app.updataeva(raw,app.testdata{i,2}{1});
                        [app.plotssim_a(i,1), app.plotmse_a(i,1),app.plotpsnr_a(i,1)]=app.updataeva(double(app.modelSelNow.predict(app.testdata{i,1}{1})),app.testdata{i,2}{1});
                        waitbar(i/app.datanum,w,sprintf('%d / %d',i,app.datanum));
                    end    
                end
                delete(w);    
                figure;
                plot(app.plotssim_a);
                hold on;
                plot(app.plotssim_b);
                plot(app.plotssim_a-app.plotssim_b);
                hold off;
    
                figure;
                plot(app.plotmse_a);
                hold on;
                plot(app.plotmse_b);
                plot(app.plotmse_a-app.plotmse_b);
                hold off;
    
                figure;
                plot(app.plotpsnr_a);
                hold on;
                plot(app.plotpsnr_b);
                plot(app.plotpsnr_a-app.plotpsnr_b);
                hold off;
            end
        end

        % Button pushed function: Button_3
        function AutoPickPushed(app, event)
            %先检查是否运行过所有参数
            if app.alleva_f==0 %0表示还没有产生所有数据，需要运行那个回调函数
                app.showAllevaButtonPushed();
            end
            selectedBtn=app.ButtonGroup.SelectedObject;
            switch (selectedBtn.Text)
                case 'SSIM'
                    [~,idx]=sort(app.plotssim_a,'descend');%从大到小
                    for i=1:app.selNum.Value
                        app.maker(end+1)=app.addmaker(6,idx(i));
                    end
                case 'MSE'
                    [~,idx]=sort(app.plotmse_a);%从小到大
                    for i=1:app.selNum.Value
                        app.maker(end+1)=app.addmaker(6,idx(i));
                    end
                case 'PSNR'
                    [~,idx]=sort(app.plotpsnr_a,'descend');%从大到小
                    for i=1:app.selNum.Value
                        app.maker(end+1)=app.addmaker(6,idx(i));
                    end
            end
        end

        % Clicked callback: selList
        function selListClicked(app, event)
            idx = event.InteractionInformation.Item;
            selimg=str2num(app.selList.Items{idx});%要查看数据的索引
            %创建一个cell：模糊图，清晰图，重构图，ssima,ssimb,msea,mseb,psnra,psnrb
            TxData={app.testdata{selimg,1}{1},...%模糊图
                app.testdata{selimg,2}{1},...%清晰图
                app.modelSelNow.predict(app.testdata{selimg,1}{1}),...%重构图
                app.plotssim_a(selimg),...
                app.plotmse_a(selimg),...
                app.plotpsnr_a(selimg),...
                app.plotssim_b(selimg),...
                app.plotmse_b(selimg),...
                app.plotpsnr_b(selimg)
            };
            app.ex_model=model_showSeldata(app,TxData);
        end

        % Button pushed function: Button_4
        function saveEvaPushed(app, event)
            selectedBtn=app.ButtonGroup.SelectedObject;
            switch (selectedBtn.Text)
                case 'SSIM'
                    assignin("base",'ssim_after',app.plotssim_a);
                    assignin("base",'ssim_before',app.plotssim_b);
                case 'MSE'
                    assignin("base",'MSE_after',app.plotmse_a);
                    assignin("base",'MSE_before',app.plotmse_b);
                case 'PSNR'
                    assignin("base",'PSNR_after',app.plotpsnr_a);
                    assignin("base",'PSNR_before',app.plotpsnr_b); 
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '模型浏览器';
            app.UIAxe_true_1 = uiaxes(app.UIFigure);
            title(app.UIAxe_true_1, 'Title')
            xlabel(app.UIAxe_true_1, 'X')
            ylabel(app.UIAxe_true_1, 'Y')
            zlabel(app.UIAxe_true_1, 'Z')
            app.UIAxe_true_2 = uiaxes(app.UIFigure);
            title(app.UIAxe_true_2, 'Title')
            xlabel(app.UIAxe_true_2, 'X')
            ylabel(app.UIAxe_true_2, 'Y')
            zlabel(app.UIAxe_true_2, 'Z')
            app.UIAxe_true_3 = uiaxes(app.UIFigure);
            title(app.UIAxe_true_3, 'Title')
            xlabel(app.UIAxe_true_3, 'X')
            ylabel(app.UIAxe_true_3, 'Y')
            zlabel(app.UIAxe_true_3, 'Z')
            app.UIAxe_true_4 = uiaxes(app.UIFigure);
            title(app.UIAxe_true_4, 'Title')
            xlabel(app.UIAxe_true_4, 'X')
            ylabel(app.UIAxe_true_4, 'Y')
            zlabel(app.UIAxe_true_4, 'Z')
            app.UIAxe_true_5 = uiaxes(app.UIFigure);
            title(app.UIAxe_true_5, 'Title')
            xlabel(app.UIAxe_true_5, 'X')
            ylabel(app.UIAxe_true_5, 'Y')
            zlabel(app.UIAxe_true_5, 'Z')
            app.UIAxe_raw_1 = uiaxes(app.UIFigure);
            title(app.UIAxe_raw_1, 'Title')
            xlabel(app.UIAxe_raw_1, 'X')
            ylabel(app.UIAxe_raw_1, 'Y')
            zlabel(app.UIAxe_raw_1, 'Z')
            app.UIAxe_raw_2 = uiaxes(app.UIFigure);
            title(app.UIAxe_raw_2, 'Title')
            xlabel(app.UIAxe_raw_2, 'X')
            ylabel(app.UIAxe_raw_2, 'Y')
            zlabel(app.UIAxe_raw_2, 'Z')
            app.UIAxe_raw_3 = uiaxes(app.UIFigure);
            title(app.UIAxe_raw_3, 'Title')
            xlabel(app.UIAxe_raw_3, 'X')
            ylabel(app.UIAxe_raw_3, 'Y')
            zlabel(app.UIAxe_raw_3, 'Z')
            app.UIAxe_raw_4 = uiaxes(app.UIFigure);
            title(app.UIAxe_raw_4, 'Title')
            xlabel(app.UIAxe_raw_4, 'X')
            ylabel(app.UIAxe_raw_4, 'Y')
            zlabel(app.UIAxe_raw_4, 'Z')
            app.UIAxe_raw_5 = uiaxes(app.UIFigure);
            title(app.UIAxe_raw_5, 'Title')
            xlabel(app.UIAxe_raw_5, 'X')
            ylabel(app.UIAxe_raw_5, 'Y')
            zlabel(app.UIAxe_raw_5, 'Z')
            app.UIAxe_out_1 = uiaxes(app.UIFigure);
            title(app.UIAxe_out_1, 'Title')
            xlabel(app.UIAxe_out_1, 'X')
            ylabel(app.UIAxe_out_1, 'Y')
            zlabel(app.UIAxe_out_1, 'Z')
            app.UIAxe_out_2 = uiaxes(app.UIFigure);
            title(app.UIAxe_out_2, 'Title')
            xlabel(app.UIAxe_out_2, 'X')
            ylabel(app.UIAxe_out_2, 'Y')
            zlabel(app.UIAxe_out_2, 'Z')
            app.UIAxe_out_3 = uiaxes(app.UIFigure);
            title(app.UIAxe_out_3, 'Title')
            xlabel(app.UIAxe_out_3, 'X')
            ylabel(app.UIAxe_out_3, 'Y')
            zlabel(app.UIAxe_out_3, 'Z')
            app.UIAxe_out_4 = uiaxes(app.UIFigure);
            title(app.UIAxe_out_4, 'Title')
            xlabel(app.UIAxe_out_4, 'X')
            ylabel(app.UIAxe_out_4, 'Y')
            zlabel(app.UIAxe_out_4, 'Z')
            app.UIAxe_out_5 = uiaxes(app.UIFigure);
            title(app.UIAxe_out_5, 'Title')
            xlabel(app.UIAxe_out_5, 'X')
            ylabel(app.UIAxe_out_5, 'Y')
            zlabel(app.UIAxe_out_5, 'Z')
            app.UIAxe_raw3d_1 = uiaxes(app.UIFigure);
            title(app.UIAxe_raw3d_1, 'Title')
            xlabel(app.UIAxe_raw3d_1, 'X')
            ylabel(app.UIAxe_raw3d_1, 'Y')
            zlabel(app.UIAxe_raw3d_1, 'Z')
            app.UIAxe_out3d_1 = uiaxes(app.UIFigure);
            title(app.UIAxe_out3d_1, 'Title')
            xlabel(app.UIAxe_out3d_1, 'X')
            ylabel(app.UIAxe_out3d_1, 'Y')
            zlabel(app.UIAxe_out3d_1, 'Z')
            app.UIAxe_true3d_1 = uiaxes(app.UIFigure);
            title(app.UIAxe_true3d_1, 'Title')
            xlabel(app.UIAxe_true3d_1, 'X')
            ylabel(app.UIAxe_true3d_1, 'Y')
            zlabel(app.UIAxe_true3d_1, 'Z')
            app.UIAxe_raw3d_2 = uiaxes(app.UIFigure);
            title(app.UIAxe_raw3d_2, 'Title')
            xlabel(app.UIAxe_raw3d_2, 'X')
            ylabel(app.UIAxe_raw3d_2, 'Y')
            zlabel(app.UIAxe_raw3d_2, 'Z')
            app.UIAxe_out3d_2 = uiaxes(app.UIFigure);
            title(app.UIAxe_out3d_2, 'Title')
            xlabel(app.UIAxe_out3d_2, 'X')
            ylabel(app.UIAxe_out3d_2, 'Y')
            zlabel(app.UIAxe_out3d_2, 'Z')
            app.UIAxe_true3d_2 = uiaxes(app.UIFigure);
            title(app.UIAxe_true3d_2, 'Title')
            xlabel(app.UIAxe_true3d_2, 'X')
            ylabel(app.UIAxe_true3d_2, 'Y')
            zlabel(app.UIAxe_true3d_2, 'Z')
            app.UIAxe_raw3d_3 = uiaxes(app.UIFigure);
            title(app.UIAxe_raw3d_3, 'Title')
            xlabel(app.UIAxe_raw3d_3, 'X')
            ylabel(app.UIAxe_raw3d_3, 'Y')
            zlabel(app.UIAxe_raw3d_3, 'Z')
            app.UIAxe_out3d_3 = uiaxes(app.UIFigure);
            title(app.UIAxe_out3d_3, 'Title')
            xlabel(app.UIAxe_out3d_3, 'X')
            ylabel(app.UIAxe_out3d_3, 'Y')
            zlabel(app.UIAxe_out3d_3, 'Z')
            app.UIAxe_true3d_3 = uiaxes(app.UIFigure);
            title(app.UIAxe_true3d_3, 'Title')
            xlabel(app.UIAxe_true3d_3, 'X')
            ylabel(app.UIAxe_true3d_3, 'Y')
            zlabel(app.UIAxe_true3d_3, 'Z')
            app.UIAxe_true3d_4 = uiaxes(app.UIFigure);
            title(app.UIAxe_true3d_4, 'Title')
            xlabel(app.UIAxe_true3d_4, 'X')
            ylabel(app.UIAxe_true3d_4, 'Y')
            zlabel(app.UIAxe_true3d_4, 'Z')
            app.UIAxe_out3d_4 = uiaxes(app.UIFigure);
            title(app.UIAxe_out3d_4, 'Title')
            xlabel(app.UIAxe_out3d_4, 'X')
            ylabel(app.UIAxe_out3d_4, 'Y')
            zlabel(app.UIAxe_out3d_4, 'Z')
            app.UIAxe_raw3d_4 = uiaxes(app.UIFigure);
            title(app.UIAxe_raw3d_4, 'Title')
            xlabel(app.UIAxe_raw3d_4, 'X')
            ylabel(app.UIAxe_raw3d_4, 'Y')
            zlabel(app.UIAxe_raw3d_4, 'Z')
            app.UIAxe_true3d_5 = uiaxes(app.UIFigure);
            title(app.UIAxe_true3d_5, 'Title')
            xlabel(app.UIAxe_true3d_5, 'X')
            ylabel(app.UIAxe_true3d_5, 'Y')
            zlabel(app.UIAxe_true3d_5, 'Z')
            app.UIAxe_out3d_5 = uiaxes(app.UIFigure);
            title(app.UIAxe_out3d_5, 'Title')
            xlabel(app.UIAxe_out3d_5, 'X')
            ylabel(app.UIAxe_out3d_5, 'Y')
            zlabel(app.UIAxe_out3d_5, 'Z')
            app.UIAxe_raw3d_5 = uiaxes(app.UIFigure);
            title(app.UIAxe_raw3d_5, 'Title')
            xlabel(app.UIAxe_raw3d_5, 'X')
            ylabel(app.UIAxe_raw3d_5, 'Y')
            zlabel(app.UIAxe_raw3d_5, 'Z')
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '数据总数：';
            app.testDataNum = uilabel(app.UIFigure);
            app.testDataNum.Text = '0';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '当前位置：';
            app.testDataNumNow = uilabel(app.UIFigure);
            app.testDataNumNow.Text = '0';
            app.NextGroup = uibutton(app.UIFigure, 'push');
            app.NextGroup.ButtonPushedFcn = createCallbackFcn(app, @NextGroupButtonPushed, true);
            app.NextGroup.Text = '下一组';
            app.PrvGroup = uibutton(app.UIFigure, 'push');
            app.PrvGroup.ButtonPushedFcn = createCallbackFcn(app, @PrvGroupButtonPushed, true);
            app.PrvGroup.Text = '上一组';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '标签数据';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '原始数据';
            app.addSel_1 = uibutton(app.UIFigure, 'push');
            app.addSel_1.ButtonPushedFcn = createCallbackFcn(app, @addSel_1ButtonPushed, true);
            app.addSel_1.Text = '选择';
            app.addSel_2 = uibutton(app.UIFigure, 'push');
            app.addSel_2.ButtonPushedFcn = createCallbackFcn(app, @addSel_2ButtonPushed, true);
            app.addSel_2.Text = '选择';
            app.addSel_3 = uibutton(app.UIFigure, 'push');
            app.addSel_3.ButtonPushedFcn = createCallbackFcn(app, @addSel_3ButtonPushed, true);
            app.addSel_3.Text = '选择';
            app.addSel_4 = uibutton(app.UIFigure, 'push');
            app.addSel_4.ButtonPushedFcn = createCallbackFcn(app, @addSel_4ButtonPushed, true);
            app.addSel_4.Text = '选择';
            app.addSel_5 = uibutton(app.UIFigure, 'push');
            app.addSel_5.ButtonPushedFcn = createCallbackFcn(app, @addSel_5ButtonPushed, true);
            app.addSel_5.Text = '选择';
            app.printImg = uibutton(app.UIFigure, 'push');
            app.printImg.ButtonPushedFcn = createCallbackFcn(app, @printImgButtonPushed, true);
            app.printImg.Text = '产生图片';
            app.selList = uilistbox(app.UIFigure);
            app.selList.Items = {};
            app.selList.ClickedFcn = createCallbackFcn(app, @selListClicked, true);
            app.selList.Value = {};
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '输出数据';
            app.selIndex = uibutton(app.UIFigure, 'push');
            app.selIndex.ButtonPushedFcn = createCallbackFcn(app, @selIndexButtonPushed, true);
            app.selIndex.Text = '定位';
            app.clearMaker = uibutton(app.UIFigure, 'push');
            app.clearMaker.ButtonPushedFcn = createCallbackFcn(app, @clearMakerButtonPushed, true);
            app.clearMaker.Text = '清除选图';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MatlabTab = uitab(app.TabGroup);
            app.MatlabTab.Title = 'Matlab';
            app.importModel = uibutton(app.MatlabTab, 'push');
            app.importModel.ButtonPushedFcn = createCallbackFcn(app, @importModelPushed, true);
            app.importModel.Text = '导入模型';
            app.clearModel = uibutton(app.MatlabTab, 'push');
            app.clearModel.ButtonPushedFcn = createCallbackFcn(app, @clearModelButtonPushed, true);
            app.clearModel.Text = '清除';
            app.Label = uilabel(app.MatlabTab);
            app.Label.Text = '选择模型';
            app.ModelList = uilistbox(app.MatlabTab);
            app.ModelList.Items = {};
            app.ModelList.ValueChangedFcn = createCallbackFcn(app, @ModelListValueChanged, true);
            app.ModelList.Value = {};
            app.draw3D = uibutton(app.MatlabTab, 'push');
            app.draw3D.ButtonPushedFcn = createCallbackFcn(app, @draw3DButtonPushed, true);
            app.draw3D.Text = '绘制';
            app.importTestData = uibutton(app.MatlabTab, 'push');
            app.importTestData.ButtonPushedFcn = createCallbackFcn(app, @importTestDataButtonPushed, true);
            app.importTestData.Text = '导入测试数据';
            app.clearData = uibutton(app.MatlabTab, 'push');
            app.clearData.Text = '清除';
            app.PythonTab = uitab(app.TabGroup);
            app.PythonTab.Title = 'Python';
            app.Button_2 = uibutton(app.PythonTab, 'push');
            app.Button_2.Text = '读取文件';
            app.SSIMLabel = uilabel(app.UIFigure);
            app.SSIMLabel.Text = 'SSIM：';
            app.SSIMLabel_2 = uilabel(app.UIFigure);
            app.SSIMLabel_2.Text = 'SSIM：';
            app.SSIMLabel_3 = uilabel(app.UIFigure);
            app.SSIMLabel_3.Text = 'SSIM：';
            app.SSIMLabel_4 = uilabel(app.UIFigure);
            app.SSIMLabel_4.Text = 'SSIM：';
            app.SSIMLabel_5 = uilabel(app.UIFigure);
            app.SSIMLabel_5.Text = 'SSIM：';
            app.MSELabel = uilabel(app.UIFigure);
            app.MSELabel.Text = 'MSE：';
            app.MSELabel_2 = uilabel(app.UIFigure);
            app.MSELabel_2.Text = 'MSE：';
            app.MSELabel_3 = uilabel(app.UIFigure);
            app.MSELabel_3.Text = 'MSE：';
            app.MSELabel_4 = uilabel(app.UIFigure);
            app.MSELabel_4.Text = 'MSE：';
            app.MSELabel_5 = uilabel(app.UIFigure);
            app.MSELabel_5.Text = 'MSE：';
            app.PSNRLabel = uilabel(app.UIFigure);
            app.PSNRLabel.Text = 'PSNR：';
            app.PSNRLabel_2 = uilabel(app.UIFigure);
            app.PSNRLabel_2.Text = 'PSNR：';
            app.PSNRLabel_3 = uilabel(app.UIFigure);
            app.PSNRLabel_3.Text = 'PSNR：';
            app.PSNRLabel_4 = uilabel(app.UIFigure);
            app.PSNRLabel_4.Text = 'PSNR：';
            app.PSNRLabel_5 = uilabel(app.UIFigure);
            app.PSNRLabel_5.Text = 'PSNR：';
            app.eva_ssim_b_1 = uilabel(app.UIFigure);
            app.eva_ssim_b_1.Text = '0';
            app.eva_mse_b_1 = uilabel(app.UIFigure);
            app.eva_mse_b_1.Text = '0';
            app.eva_psnr_b_1 = uilabel(app.UIFigure);
            app.eva_psnr_b_1.Text = '0';
            app.eva_psnr_b_2 = uilabel(app.UIFigure);
            app.eva_psnr_b_2.Text = '0';
            app.eva_mse_b_2 = uilabel(app.UIFigure);
            app.eva_mse_b_2.Text = '0';
            app.eva_ssim_b_2 = uilabel(app.UIFigure);
            app.eva_ssim_b_2.Text = '0';
            app.eva_psnr_b_3 = uilabel(app.UIFigure);
            app.eva_psnr_b_3.Text = '0';
            app.eva_mse_b_3 = uilabel(app.UIFigure);
            app.eva_mse_b_3.Text = '0';
            app.eva_ssim_b_3 = uilabel(app.UIFigure);
            app.eva_ssim_b_3.Text = '0';
            app.eva_psnr_b_4 = uilabel(app.UIFigure);
            app.eva_psnr_b_4.Text = '0';
            app.eva_mse_b_4 = uilabel(app.UIFigure);
            app.eva_mse_b_4.Text = '0';
            app.eva_ssim_b_4 = uilabel(app.UIFigure);
            app.eva_ssim_b_4.Text = '0';
            app.eva_psnr_b_5 = uilabel(app.UIFigure);
            app.eva_psnr_b_5.Text = '0';
            app.eva_mse_b_5 = uilabel(app.UIFigure);
            app.eva_mse_b_5.Text = '0';
            app.eva_ssim_b_5 = uilabel(app.UIFigure);
            app.eva_ssim_b_5.Text = '0';
            app.eva_psnr_a_1 = uilabel(app.UIFigure);
            app.eva_psnr_a_1.Text = '0';
            app.eva_mse_a_1 = uilabel(app.UIFigure);
            app.eva_mse_a_1.Text = '0';
            app.eva_ssim_a_1 = uilabel(app.UIFigure);
            app.eva_ssim_a_1.Text = '0';
            app.MSELabel_6 = uilabel(app.UIFigure);
            app.MSELabel_6.Text = 'MSE：';
            app.SSIMLabel_6 = uilabel(app.UIFigure);
            app.SSIMLabel_6.Text = 'SSIM：';
            app.PSNRLabel_6 = uilabel(app.UIFigure);
            app.PSNRLabel_6.Text = 'PSNR：';
            app.BeforeLabel = uilabel(app.UIFigure);
            app.BeforeLabel.Text = 'Before';
            app.AfterLabel = uilabel(app.UIFigure);
            app.AfterLabel.Text = 'After';
            app.eva_psnr_e_1 = uilabel(app.UIFigure);
            app.eva_psnr_e_1.Text = '0';
            app.eva_mse_e_1 = uilabel(app.UIFigure);
            app.eva_mse_e_1.Text = '0';
            app.MSELabel_7 = uilabel(app.UIFigure);
            app.MSELabel_7.Text = 'MSE：';
            app.PSNRLabel_7 = uilabel(app.UIFigure);
            app.PSNRLabel_7.Text = 'PSNR：';
            app.eva_ssim_e_1 = uilabel(app.UIFigure);
            app.eva_ssim_e_1.Text = '0';
            app.SSIMLabel_7 = uilabel(app.UIFigure);
            app.SSIMLabel_7.Text = 'SSIM：';
            app.ImproveLabel = uilabel(app.UIFigure);
            app.ImproveLabel.Text = 'Improve';
            app.eva_psnr_a_2 = uilabel(app.UIFigure);
            app.eva_psnr_a_2.Text = '0';
            app.eva_mse_a_2 = uilabel(app.UIFigure);
            app.eva_mse_a_2.Text = '0';
            app.eva_ssim_a_2 = uilabel(app.UIFigure);
            app.eva_ssim_a_2.Text = '0';
            app.MSELabel_8 = uilabel(app.UIFigure);
            app.MSELabel_8.Text = 'MSE：';
            app.SSIMLabel_8 = uilabel(app.UIFigure);
            app.SSIMLabel_8.Text = 'SSIM：';
            app.PSNRLabel_8 = uilabel(app.UIFigure);
            app.PSNRLabel_8.Text = 'PSNR：';
            app.eva_psnr_e_2 = uilabel(app.UIFigure);
            app.eva_psnr_e_2.Text = '0';
            app.eva_mse_e_2 = uilabel(app.UIFigure);
            app.eva_mse_e_2.Text = '0';
            app.MSELabel_9 = uilabel(app.UIFigure);
            app.MSELabel_9.Text = 'MSE：';
            app.PSNRLabel_9 = uilabel(app.UIFigure);
            app.PSNRLabel_9.Text = 'PSNR：';
            app.eva_ssim_e_2 = uilabel(app.UIFigure);
            app.eva_ssim_e_2.Text = '0';
            app.SSIMLabel_9 = uilabel(app.UIFigure);
            app.SSIMLabel_9.Text = 'SSIM：';
            app.ImproveLabel_2 = uilabel(app.UIFigure);
            app.ImproveLabel_2.Text = 'Improve';
            app.AfterLabel_2 = uilabel(app.UIFigure);
            app.AfterLabel_2.Text = 'After';
            app.BeforeLabel_2 = uilabel(app.UIFigure);
            app.BeforeLabel_2.Text = 'Before';
            app.BeforeLabel_3 = uilabel(app.UIFigure);
            app.BeforeLabel_3.Text = 'Before';
            app.eva_psnr_a_3 = uilabel(app.UIFigure);
            app.eva_psnr_a_3.Text = '0';
            app.eva_mse_a_3 = uilabel(app.UIFigure);
            app.eva_mse_a_3.Text = '0';
            app.eva_ssim_a_3 = uilabel(app.UIFigure);
            app.eva_ssim_a_3.Text = '0';
            app.MSELabel_10 = uilabel(app.UIFigure);
            app.MSELabel_10.Text = 'MSE：';
            app.SSIMLabel_10 = uilabel(app.UIFigure);
            app.SSIMLabel_10.Text = 'SSIM：';
            app.PSNRLabel_10 = uilabel(app.UIFigure);
            app.PSNRLabel_10.Text = 'PSNR：';
            app.eva_psnr_e_3 = uilabel(app.UIFigure);
            app.eva_psnr_e_3.Text = '0';
            app.eva_mse_e_3 = uilabel(app.UIFigure);
            app.eva_mse_e_3.Text = '0';
            app.MSELabel_11 = uilabel(app.UIFigure);
            app.MSELabel_11.Text = 'MSE：';
            app.PSNRLabel_11 = uilabel(app.UIFigure);
            app.PSNRLabel_11.Text = 'PSNR：';
            app.eva_ssim_e_3 = uilabel(app.UIFigure);
            app.eva_ssim_e_3.Text = '0';
            app.SSIMLabel_11 = uilabel(app.UIFigure);
            app.SSIMLabel_11.Text = 'SSIM：';
            app.ImproveLabel_3 = uilabel(app.UIFigure);
            app.ImproveLabel_3.Text = 'Improve';
            app.AfterLabel_3 = uilabel(app.UIFigure);
            app.AfterLabel_3.Text = 'After';
            app.BeforeLabel_4 = uilabel(app.UIFigure);
            app.BeforeLabel_4.Text = 'Before';
            app.eva_psnr_a_4 = uilabel(app.UIFigure);
            app.eva_psnr_a_4.Text = '0';
            app.eva_mse_a_4 = uilabel(app.UIFigure);
            app.eva_mse_a_4.Text = '0';
            app.eva_ssim_a_4 = uilabel(app.UIFigure);
            app.eva_ssim_a_4.Text = '0';
            app.MSELabel_12 = uilabel(app.UIFigure);
            app.MSELabel_12.Text = 'MSE：';
            app.SSIMLabel_12 = uilabel(app.UIFigure);
            app.SSIMLabel_12.Text = 'SSIM：';
            app.PSNRLabel_12 = uilabel(app.UIFigure);
            app.PSNRLabel_12.Text = 'PSNR：';
            app.AfterLabel_4 = uilabel(app.UIFigure);
            app.AfterLabel_4.Text = 'After';
            app.eva_psnr_e_4 = uilabel(app.UIFigure);
            app.eva_psnr_e_4.Text = '0';
            app.eva_mse_e_4 = uilabel(app.UIFigure);
            app.eva_mse_e_4.Text = '0';
            app.MSELabel_13 = uilabel(app.UIFigure);
            app.MSELabel_13.Text = 'MSE：';
            app.PSNRLabel_13 = uilabel(app.UIFigure);
            app.PSNRLabel_13.Text = 'PSNR：';
            app.eva_ssim_e_4 = uilabel(app.UIFigure);
            app.eva_ssim_e_4.Text = '0';
            app.SSIMLabel_13 = uilabel(app.UIFigure);
            app.SSIMLabel_13.Text = 'SSIM：';
            app.ImproveLabel_4 = uilabel(app.UIFigure);
            app.ImproveLabel_4.Text = 'Improve';
            app.eva_psnr_a_5 = uilabel(app.UIFigure);
            app.eva_psnr_a_5.Text = '0';
            app.eva_mse_a_5 = uilabel(app.UIFigure);
            app.eva_mse_a_5.Text = '0';
            app.eva_ssim_a_5 = uilabel(app.UIFigure);
            app.eva_ssim_a_5.Text = '0';
            app.MSELabel_14 = uilabel(app.UIFigure);
            app.MSELabel_14.Text = 'MSE：';
            app.SSIMLabel_14 = uilabel(app.UIFigure);
            app.SSIMLabel_14.Text = 'SSIM：';
            app.PSNRLabel_14 = uilabel(app.UIFigure);
            app.PSNRLabel_14.Text = 'PSNR：';
            app.AfterLabel_5 = uilabel(app.UIFigure);
            app.AfterLabel_5.Text = 'After';
            app.eva_psnr_e_5 = uilabel(app.UIFigure);
            app.eva_psnr_e_5.Text = '0';
            app.eva_mse_e_5 = uilabel(app.UIFigure);
            app.eva_mse_e_5.Text = '0';
            app.MSELabel_15 = uilabel(app.UIFigure);
            app.MSELabel_15.Text = 'MSE：';
            app.PSNRLabel_15 = uilabel(app.UIFigure);
            app.PSNRLabel_15.Text = 'PSNR：';
            app.eva_ssim_e_5 = uilabel(app.UIFigure);
            app.eva_ssim_e_5.Text = '0';
            app.SSIMLabel_15 = uilabel(app.UIFigure);
            app.SSIMLabel_15.Text = 'SSIM：';
            app.ImproveLabel_5 = uilabel(app.UIFigure);
            app.ImproveLabel_5.Text = 'Improve';
            app.BeforeLabel_5 = uilabel(app.UIFigure);
            app.BeforeLabel_5.Text = 'Before';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '自动选择';
            app.printImg_2 = uibutton(app.Panel, 'push');
            app.printImg_2.ButtonPushedFcn = createCallbackFcn(app, @showAllevaButtonPushed, true);
            app.printImg_2.Text = '全部测试图像的评价指标';
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.ButtonGroup.Title = '按什么选';
            app.SSIMButton = uiradiobutton(app.ButtonGroup);
            app.SSIMButton.Text = 'SSIM';
            app.SSIMButton.Value = true;
            app.MSEButton = uiradiobutton(app.ButtonGroup);
            app.MSEButton.Text = 'MSE';
            app.PSNRButton = uiradiobutton(app.ButtonGroup);
            app.PSNRButton.Text = 'PSNR';
            app.Label_8 = uilabel(app.Panel);
            app.Label_8.Text = '选几张';
            app.selNum = uispinner(app.Panel);
            app.selNum.Limits = [1 10];
            app.selNum.Value = 5;
            app.Button_3 = uibutton(app.Panel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @AutoPickPushed, true);
            app.Button_3.Text = '自动选择';
            app.Button_4 = uibutton(app.Panel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @saveEvaPushed, true);
            app.Button_4.Text = '保存指标曲线';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '点击查看已选择数据';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = model

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end