classdef model_showSeldata < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        ImproveLabel   matlab.ui.control.Label
        SSIMLabel_3    matlab.ui.control.Label
        eva_ssim_e     matlab.ui.control.Label
        PSNRLabel_3    matlab.ui.control.Label
        MSELabel_3     matlab.ui.control.Label
        eva_mse_e      matlab.ui.control.Label
        eva_psnr_e     matlab.ui.control.Label
        AfterLabel     matlab.ui.control.Label
        BeforeLabel    matlab.ui.control.Label
        PSNRLabel_2    matlab.ui.control.Label
        SSIMLabel_2    matlab.ui.control.Label
        MSELabel_2     matlab.ui.control.Label
        eva_ssim_a     matlab.ui.control.Label
        eva_mse_a      matlab.ui.control.Label
        eva_psnr_a     matlab.ui.control.Label
        eva_psnr_b     matlab.ui.control.Label
        eva_mse_b      matlab.ui.control.Label
        eva_ssim_b     matlab.ui.control.Label
        PSNRLabel      matlab.ui.control.Label
        MSELabel       matlab.ui.control.Label
        SSIMLabel      matlab.ui.control.Label
        Button         matlab.ui.control.Button
        UIAxes_raw3d   matlab.ui.control.UIAxes
        UIAxes_out3d   matlab.ui.control.UIAxes
        UIAxes_true3d  matlab.ui.control.UIAxes
        UIAxes_true    matlab.ui.control.UIAxes
        UIAxes_out     matlab.ui.control.UIAxes
        UIAxes_raw     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        model % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, imgData)
            %处理传过来的数据，显示在视图中
            app.model=caller;
            %显示图像
            mesh(app.UIAxes_raw,imgData{1});%模糊图
            view(app.UIAxes_raw,[0,-90]);
            colormap(app.UIAxes_raw,"turbo");
            colorbar(app.UIAxes_raw);

            mesh(app.UIAxes_true,imgData{2});%清晰图
            view(app.UIAxes_true,[0,-90]);
            colormap(app.UIAxes_true,"turbo");
            colorbar(app.UIAxes_true);

            mesh(app.UIAxes_out,imgData{3});%重构图
            view(app.UIAxes_out,[0,-90]);
            colormap(app.UIAxes_out,"turbo");
            colorbar(app.UIAxes_out);

            mesh(app.UIAxes_raw3d,imgData{1});%模糊图3d
            view(app.UIAxes_raw3d,[45,45]);
            app.UIAxes_raw3d.ZDir = 'reverse';
            colormap(app.UIAxes_raw3d,"turbo");
            colorbar(app.UIAxes_raw3d);

            mesh(app.UIAxes_true3d,imgData{2});%清晰图3d
            view(app.UIAxes_true3d,[45,45]);
            app.UIAxes_true3d.ZDir = 'reverse';
            colormap(app.UIAxes_true3d,"turbo");
            colorbar(app.UIAxes_true3d);

            mesh(app.UIAxes_out3d,imgData{3});%重构图3d
            view(app.UIAxes_out3d,[45,45]);
            app.UIAxes_out3d.ZDir = 'reverse';
            colormap(app.UIAxes_out3d,"turbo");
            colorbar(app.UIAxes_out3d);

            %显示数据
            app.eva_ssim_a.Text=num2str(imgData{4});
            app.eva_mse_a.Text=num2str(imgData{5});
            app.eva_psnr_a.Text=num2str(imgData{6});
            app.eva_ssim_b.Text=num2str(imgData{7});
            app.eva_mse_b.Text=num2str(imgData{8});
            app.eva_psnr_b.Text=num2str(imgData{9});
            app.eva_ssim_e.Text=num2str(imgData{4}-imgData{7});
            app.eva_mse_e.Text=num2str(imgData{5}-imgData{8});
            app.eva_psnr_e.Text=num2str(imgData{6}-imgData{9});

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_raw = uiaxes(app.UIFigure);
            title(app.UIAxes_raw, 'Title')
            xlabel(app.UIAxes_raw, 'X')
            ylabel(app.UIAxes_raw, 'Y')
            zlabel(app.UIAxes_raw, 'Z')
            app.UIAxes_out = uiaxes(app.UIFigure);
            title(app.UIAxes_out, 'Title')
            xlabel(app.UIAxes_out, 'X')
            ylabel(app.UIAxes_out, 'Y')
            zlabel(app.UIAxes_out, 'Z')
            app.UIAxes_true = uiaxes(app.UIFigure);
            title(app.UIAxes_true, 'Title')
            xlabel(app.UIAxes_true, 'X')
            ylabel(app.UIAxes_true, 'Y')
            zlabel(app.UIAxes_true, 'Z')
            app.UIAxes_true3d = uiaxes(app.UIFigure);
            title(app.UIAxes_true3d, 'Title')
            xlabel(app.UIAxes_true3d, 'X')
            ylabel(app.UIAxes_true3d, 'Y')
            zlabel(app.UIAxes_true3d, 'Z')
            app.UIAxes_out3d = uiaxes(app.UIFigure);
            title(app.UIAxes_out3d, 'Title')
            xlabel(app.UIAxes_out3d, 'X')
            ylabel(app.UIAxes_out3d, 'Y')
            zlabel(app.UIAxes_out3d, 'Z')
            app.UIAxes_raw3d = uiaxes(app.UIFigure);
            title(app.UIAxes_raw3d, 'Title')
            xlabel(app.UIAxes_raw3d, 'X')
            ylabel(app.UIAxes_raw3d, 'Y')
            zlabel(app.UIAxes_raw3d, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '按我';
            app.SSIMLabel = uilabel(app.UIFigure);
            app.SSIMLabel.Text = 'SSIM：';
            app.MSELabel = uilabel(app.UIFigure);
            app.MSELabel.Text = 'MSE：';
            app.PSNRLabel = uilabel(app.UIFigure);
            app.PSNRLabel.Text = 'PSNR：';
            app.eva_ssim_b = uilabel(app.UIFigure);
            app.eva_ssim_b.Text = '0';
            app.eva_mse_b = uilabel(app.UIFigure);
            app.eva_mse_b.Text = '0';
            app.eva_psnr_b = uilabel(app.UIFigure);
            app.eva_psnr_b.Text = '0';
            app.eva_psnr_a = uilabel(app.UIFigure);
            app.eva_psnr_a.Text = '0';
            app.eva_mse_a = uilabel(app.UIFigure);
            app.eva_mse_a.Text = '0';
            app.eva_ssim_a = uilabel(app.UIFigure);
            app.eva_ssim_a.Text = '0';
            app.MSELabel_2 = uilabel(app.UIFigure);
            app.MSELabel_2.Text = 'MSE：';
            app.SSIMLabel_2 = uilabel(app.UIFigure);
            app.SSIMLabel_2.Text = 'SSIM：';
            app.PSNRLabel_2 = uilabel(app.UIFigure);
            app.PSNRLabel_2.Text = 'PSNR：';
            app.BeforeLabel = uilabel(app.UIFigure);
            app.BeforeLabel.Text = 'Before';
            app.AfterLabel = uilabel(app.UIFigure);
            app.AfterLabel.Text = 'After';
            app.eva_psnr_e = uilabel(app.UIFigure);
            app.eva_psnr_e.Text = '0';
            app.eva_mse_e = uilabel(app.UIFigure);
            app.eva_mse_e.Text = '0';
            app.MSELabel_3 = uilabel(app.UIFigure);
            app.MSELabel_3.Text = 'MSE：';
            app.PSNRLabel_3 = uilabel(app.UIFigure);
            app.PSNRLabel_3.Text = 'PSNR：';
            app.eva_ssim_e = uilabel(app.UIFigure);
            app.eva_ssim_e.Text = '0';
            app.SSIMLabel_3 = uilabel(app.UIFigure);
            app.SSIMLabel_3.Text = 'SSIM：';
            app.ImproveLabel = uilabel(app.UIFigure);
            app.ImproveLabel.Text = 'Improve';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = model_showSeldata(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end